classdef attset_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpecifyattributesetUIFigure   matlab.ui.Figure
        CurrentattributesetEditFieldLabel  matlab.ui.control.Label
        CurrentattributesetEditField  matlab.ui.control.NumericEditField
        UITableAttSet                 matlab.ui.control.Table
        CancelButton                  matlab.ui.control.Button
        SaveExportButton              matlab.ui.control.Button
        PreviewButton                 matlab.ui.control.Button
        UIAxesPreview                 matlab.ui.control.UIAxes
    end


    properties (Access = private)
        callingApp % Main app object
    end

    methods (Access = private)
        
        function [isgood,msg] = validateTableData(app)
            isgood=true;
            msg='';
            if all(all(cellfun(@isempty,app.UITableAttSet.Data)))
                isgood=false;
                msg='All parameters must be informed!';
                return;
            end
            testdata=cellfun(@str2double,app.UITableAttSet.Data(:,2:end-1));
            isvalidnumber=all(all(~isnan(testdata)));
            if ~isvalidnumber
                isgood=false;
                msg='Invalid numerical data!';
                return;
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, numph, numsw, attset, currattsetdata)
            app.callingApp = mainapp;
            [wdir,~,~] = fileparts(mfilename('fullpath'));
            fname = 'condudb.mat';
            try
                load(fullfile(wdir,fname),'condudb_data');
                app.CurrentattributesetEditField.Value=attset;
                app.UITableAttSet.Data=cell((numph+numsw),6);
                app.UITableAttSet.RowName = 'numbered';
                app.UITableAttSet.ColumnFormat={condudb_data(:,1)' {'1','2','4'} [] [] [] []};
                if numph==3
                    phid={'Phase A' 'Phase B' 'Phase C'};
                else
                    phid={'Phase A - C1' 'Phase B - C1' 'Phase C - C1' 'Phase A - C2' 'Phase B - C2' 'Phase C - C2'};
                end
                swid={};
                if numsw==1
                    swid={'Shield wire'};
                elseif numsw==2
                    swid={'Shield wire 1' 'Shield wire 2'};
                end
                allconds= [phid swid]';
                app.UITableAttSet.Data(:,6)=allconds;
                for k=1:(numph+numsw)
                    app.UITableAttSet.Data(k,3)={'0'};
                end
                try
                    data_load=jsondecode(char(currattsetdata));
                    if ~strcmp(data_load,'undefined')
                        app.UITableAttSet.Data=struct2cell(data_load)';
                    end
                catch
                end
                %currattsetdata gotta do something
            catch
                uiwait(msgbox('No conductor database found. Please create one before using this app.','Error','error'));
                try
                    addpath('assets');
                    condudb_app
                catch
                    f = msgbox('Conductor database app not found!','Error','error');
                end
                app.delete;
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
             app.delete;
        end

        % Close request function: SpecifyattributesetUIFigure
        function SpecifyattributesetUIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: SaveExportButton
        function SaveExportButtonPushed(app, event)
            % app.callingApp.UITableAttSets.Data(1)
            [isgood,msg] = validateTableData(app);
            if ~isgood
                f = msgbox(msg,'You lose, fella.','error');
                return;
            end
            
            thisattset=app.CurrentattributesetEditField.Value;
            structArray = cell2struct(app.UITableAttSet.Data, {'conductor', 'bundled', 'bundle_spac','horizontal','vertical','type'},2);
            app.callingApp.UITableAttSets.Data(thisattset)=cellstr(jsonencode(structArray));
            delete(app);
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            plotdata=cellfun(@str2double, app.UITableAttSet.Data(:,4:5));
            try
                scatter(app.UIAxesPreview, plotdata(:,1), plotdata(:,2),'filled');
%                 app.UIAxesPreview.Position=[650 14 187 228];
%                 axis(app.UIAxesPreview, 'tight');
%                 app.UIAxesPreview.XLim = [.9*min(plotdata(:,1)) 1.1*max(plotdata(:,1))];
%                 app.UIAxesPreview.YLim = [.9*min(plotdata(:,2)) 1.1*max(plotdata(:,2))];
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpecifyattributesetUIFigure = uifigure;
            app.SpecifyattributesetUIFigure.Name = 'Specify attribute set';
            app.SpecifyattributesetUIFigure.CloseRequestFcn = createCallbackFcn(app, @SpecifyattributesetUIFigureCloseRequest, true);
            app.CurrentattributesetEditFieldLabel = uilabel(app.SpecifyattributesetUIFigure);
            app.CurrentattributesetEditFieldLabel.Text = 'Current attribute set #:';
            app.CurrentattributesetEditField = uieditfield(app.SpecifyattributesetUIFigure, 'numeric');
            app.CurrentattributesetEditField.ValueDisplayFormat = '%.0f';
            app.CurrentattributesetEditField.Editable = 'off';
            app.UITableAttSet = uitable(app.SpecifyattributesetUIFigure);
            app.UITableAttSet.ColumnName = {'Conductor'; '# Cond.'; 'S [m]'; 'Horizontal [m]'; 'Vertical [m]'; 'Type'};
            app.UITableAttSet.RowName = {};
            app.UITableAttSet.ColumnEditable = [true true true true true false];
            app.CancelButton = uibutton(app.SpecifyattributesetUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SaveExportButton = uibutton(app.SpecifyattributesetUIFigure, 'push');
            app.SaveExportButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExportButtonPushed, true);
            app.SaveExportButton.Text = 'Save & Export';
            app.PreviewButton = uibutton(app.SpecifyattributesetUIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.UIAxesPreview = uiaxes(app.SpecifyattributesetUIFigure);
            xlabel(app.UIAxesPreview, 'Horizontal [m]')
            ylabel(app.UIAxesPreview, 'Vertical [m]')
        end
    end

    methods (Access = public)

        % Construct app
        function app = attset_app(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpecifyattributesetUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpecifyattributesetUIFigure)
        end
    end
end