classdef condudb_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConductordatabaseUIFigure   matlab.ui.Figure
        UITableConduDB              matlab.ui.control.Table
        NewconductorPanel           matlab.ui.container.Panel
        IDEditFieldLabel            matlab.ui.control.Label
        IDEditField                 matlab.ui.control.EditField
        OuterradiusmEditFieldLabel  matlab.ui.control.Label
        OuterradiusmEditField       matlab.ui.control.NumericEditField
        InnerradiusmEditFieldLabel  matlab.ui.control.Label
        InnerradiusmEditField       matlab.ui.control.NumericEditField
        ResistanceDCkmLabel         matlab.ui.control.Label
        ResistanceDCkmEditField     matlab.ui.control.NumericEditField
        AddButton                   matlab.ui.control.Button
        SelectedrowEditFieldLabel   matlab.ui.control.Label
        SelectedrowEditField        matlab.ui.control.NumericEditField
        DeleteButton                matlab.ui.control.Button
        ExitButton                  matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [wdir,~,~] = fileparts(mfilename('fullpath'));
            fname = 'condudb.mat';
            try
                load(fullfile(wdir,fname),'condudb_data');
                app.UITableConduDB.Data=condudb_data;
            catch
                f = msgbox('No conductor database found. Starting from scratch...','Warning','warn');
                return;
            end
            app.UITableConduDB.RowName = 'numbered';
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            oldData = app.UITableConduDB.Data;
            newRow= {app.IDEditField.Value app.OuterradiusmEditField.Value app.InnerradiusmEditField.Value app.ResistanceDCkmEditField.Value};
            newData = [oldData; newRow];
            app.UITableConduDB.Data=newData;
            app.IDEditField.Value='';
            app.OuterradiusmEditField.Value=0;
            app.InnerradiusmEditField.Value=0;
            app.ResistanceDCkmEditField.Value=0;
            
        end

        % Cell selection callback: UITableConduDB
        function UITableConduDBCellSelection(app, event)
            selectedRow = event.Indices(1);
            app.SelectedrowEditField.Value=selectedRow;
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            
            if app.SelectedrowEditField.Value ~= 0
                app.UITableConduDB.Data(app.SelectedrowEditField.Value,:)=[];
                app.SelectedrowEditField.Value = 0;
            end
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.delete;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [wdir,~,~] = fileparts(mfilename('fullpath'));
            fname = 'condudb.mat';
            orig_state = warning;warning('off','all');
            condudb_data=app.UITableConduDB.Data;
            save(fullfile(wdir,fname),'condudb_data');
            warning(orig_state);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConductordatabaseUIFigure = uifigure;
            app.ConductordatabaseUIFigure.Name = 'Conductor database';
            app.UITableConduDB = uitable(app.ConductordatabaseUIFigure);
            app.UITableConduDB.ColumnName = {'ID'; 'Outer radius [m]'; 'Inner radius [m]'; 'Resistance DC [Ω/km]'};
            app.UITableConduDB.RowName = {};
            app.UITableConduDB.ColumnEditable = true;
            app.UITableConduDB.CellSelectionCallback = createCallbackFcn(app, @UITableConduDBCellSelection, true);
            app.NewconductorPanel = uipanel(app.ConductordatabaseUIFigure);
            app.NewconductorPanel.Title = 'New conductor';
            app.IDEditFieldLabel = uilabel(app.NewconductorPanel);
            app.IDEditFieldLabel.Text = 'ID:';
            app.IDEditField = uieditfield(app.NewconductorPanel, 'text');
            app.OuterradiusmEditFieldLabel = uilabel(app.NewconductorPanel);
            app.OuterradiusmEditFieldLabel.Text = 'Outer radius [m]:';
            app.OuterradiusmEditField = uieditfield(app.NewconductorPanel, 'numeric');
            app.OuterradiusmEditField.Limits = [0 Inf];
            app.InnerradiusmEditFieldLabel = uilabel(app.NewconductorPanel);
            app.InnerradiusmEditFieldLabel.Text = 'Inner radius [m]:';
            app.InnerradiusmEditField = uieditfield(app.NewconductorPanel, 'numeric');
            app.InnerradiusmEditField.Limits = [0 Inf];
            app.ResistanceDCkmLabel = uilabel(app.NewconductorPanel);
            app.ResistanceDCkmLabel.Text = 'Resistance DC [Ω/km]:';
            app.ResistanceDCkmEditField = uieditfield(app.NewconductorPanel, 'numeric');
            app.ResistanceDCkmEditField.Limits = [0 Inf];
            app.AddButton = uibutton(app.NewconductorPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.SelectedrowEditFieldLabel = uilabel(app.ConductordatabaseUIFigure);
            app.SelectedrowEditFieldLabel.Text = 'Selected row:';
            app.SelectedrowEditField = uieditfield(app.ConductordatabaseUIFigure, 'numeric');
            app.SelectedrowEditField.Editable = 'off';
            app.DeleteButton = uibutton(app.ConductordatabaseUIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.ExitButton = uibutton(app.ConductordatabaseUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.SaveButton = uibutton(app.ConductordatabaseUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
        end
    end

    methods (Access = public)

        % Construct app
        function app = condudb_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConductordatabaseUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConductordatabaseUIFigure)
        end
    end
end