classdef rtfs_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RTFSRealisticTransmissionLineFaultSimulatorUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        ProjectdataPanel                matlab.ui.container.Panel
        LoadWDButton                    matlab.ui.control.Button
        ProjectIDEditFieldLabel         matlab.ui.control.Label
        ProjectIDEditField              matlab.ui.control.EditField
        WorkingdirectoryEditFieldLabel  matlab.ui.control.Label
        WorkingdirectoryEditField       matlab.ui.control.EditField
        ProjectdescriptionTextAreaLabel  matlab.ui.control.Label
        ProjectdescriptionTextArea      matlab.ui.control.TextArea
        PathstoexecutablesPanel         matlab.ui.container.Panel
        LoadATPButton                   matlab.ui.control.Button
        LoadGTPPLOTButton               matlab.ui.control.Button
        ATPsolverEditFieldLabel         matlab.ui.control.Label
        ATPsolverEditField              matlab.ui.control.EditField
        GTPPLOTEditFieldLabel           matlab.ui.control.Label
        GTPPLOTEditField                matlab.ui.control.EditField
        LoadPl42matButton               matlab.ui.control.Button
        PL42MATEditFieldLabel           matlab.ui.control.Label
        PL42MATEditField                matlab.ui.control.EditField
        ATPsettingsPanel                matlab.ui.container.Panel
        FreqHzEditFieldLabel            matlab.ui.control.Label
        FreqHzEditField                 matlab.ui.control.NumericEditField
        deltaTsEditFieldLabel           matlab.ui.control.Label
        deltaTsEditField                matlab.ui.control.NumericEditField
        TmaxsEditFieldLabel             matlab.ui.control.Label
        TmaxsEditField                  matlab.ui.control.NumericEditField
        ParallelcomputingPanel          matlab.ui.container.Panel
        EnableParaCompCheckBox          matlab.ui.control.CheckBox
        workersSpinnerLabel             matlab.ui.control.Label
        workersSpinner                  matlab.ui.control.Spinner
        LinemodelTab                    matlab.ui.container.Tab
        TypeButtonGroup                 matlab.ui.container.ButtonGroup
        BergeronButton                  matlab.ui.control.RadioButton
        PiButton                        matlab.ui.control.RadioButton
        JMartiButton                    matlab.ui.control.RadioButton
        ConfigurationButtonGroup        matlab.ui.container.ButtonGroup
        SinglecircuitButton             matlab.ui.control.RadioButton
        DoublecircuitButton             matlab.ui.control.RadioButton
        TranspositionButtonGroup        matlab.ui.container.ButtonGroup
        UntransposedButton              matlab.ui.control.RadioButton
        L3L3L3Button                    matlab.ui.control.RadioButton
        L6L3L3L6Button                  matlab.ui.control.RadioButton
        DefaultspanlengthmEditFieldLabel  matlab.ui.control.Label
        DefaultspanlengthmEditField     matlab.ui.control.NumericEditField
        UITableSL                       matlab.ui.control.Table
        SpecifyindividualsectionsLabel  matlab.ui.control.Label
        LCCfrequencyHzEditFieldLabel    matlab.ui.control.Label
        LCCfrequencyHzEditField         matlab.ui.control.NumericEditField
        btnLoadSpanLength               matlab.ui.control.Button
        ShieldwiresButtonGroup          matlab.ui.container.ButtonGroup
        SingleSWButton                  matlab.ui.control.RadioButton
        DoubleSWButton                  matlab.ui.control.RadioButton
        NoneSWButton                    matlab.ui.control.RadioButton
        TotallinelengthmEditFieldLabel  matlab.ui.control.Label
        TotallinelengthmEditField       matlab.ui.control.NumericEditField
        NumberofsectionsSpinnerLabel    matlab.ui.control.Label
        NumberofsectionsSpinner         matlab.ui.control.Spinner
        JMartimodelparametersPanel      matlab.ui.container.Panel
        UsedefaultfittingCheckBox       matlab.ui.control.CheckBox
        DecadesSpinnerLabel             matlab.ui.control.Label
        DecadesSpinner                  matlab.ui.control.Spinner
        PointsDecadeSpinnerLabel        matlab.ui.control.Label
        PointsDecadeSpinner             matlab.ui.control.Spinner
        FreqmatrixHzEditFieldLabel      matlab.ui.control.Label
        FreqmatrixHzEditField           matlab.ui.control.NumericEditField
        FreqSSHzEditFieldLabel          matlab.ui.control.Label
        FreqSSHzEditField               matlab.ui.control.NumericEditField
        ButtonEnableNumSec              matlab.ui.control.StateButton
        CrosssectionTab                 matlab.ui.container.Tab
        DefaultattributesetDropDownLabel  matlab.ui.control.Label
        DefaultattributesetDropDown     matlab.ui.control.DropDown
        UITableAttSets                  matlab.ui.control.Table
        DefineeachattributesetclicktovieweditLabel  matlab.ui.control.Label
        UITableAttSetSec                matlab.ui.control.Table
        SpecifyattributesetsforindividualsectionsLabel  matlab.ui.control.Label
        NumberofattributesetsSpinnerLabel  matlab.ui.control.Label
        NumberofattributesetsSpinner    matlab.ui.control.Spinner
        ButtonEnableAttSet              matlab.ui.control.StateButton
        Terminal1Tab                    matlab.ui.container.Tab
        Circuit1dataPanel               matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        VoltagesourceTab                matlab.ui.container.Tab
        RMSLLamplitudeVEditFieldLabel   matlab.ui.control.Label
        RMSLLamplitudeV1EditFieldT1     matlab.ui.control.NumericEditField
        FrequencyHzLabel                matlab.ui.control.Label
        FrequencyHz1EditFieldT1         matlab.ui.control.NumericEditField
        PhaseangledegLabel              matlab.ui.control.Label
        Phaseangledeg1EditFieldT1       matlab.ui.control.NumericEditField
        TstartsLabel                    matlab.ui.control.Label
        Tstarts1EditFieldT1             matlab.ui.control.NumericEditField
        TstopsLabel                     matlab.ui.control.Label
        Tstops1EditFieldT1              matlab.ui.control.NumericEditField
        ImpedancesTab                   matlab.ui.container.Tab
        RLabel                          matlab.ui.control.Label
        LmHLabel                        matlab.ui.control.Label
        CFLabel                         matlab.ui.control.Label
        GroundinggridLabel              matlab.ui.control.Label
        RGG1EditFieldT1                 matlab.ui.control.NumericEditField
        LGG1EditFieldT1                 matlab.ui.control.NumericEditField
        CGG1EditFieldT1                 matlab.ui.control.NumericEditField
        NeutralconnectionLabel          matlab.ui.control.Label
        RNC1EditFieldT1                 matlab.ui.control.NumericEditField
        LNC1EditFieldT1                 matlab.ui.control.NumericEditField
        CNC1EditFieldT1                 matlab.ui.control.NumericEditField
        LmHLabel_2                      matlab.ui.control.Label
        RLabel_2                        matlab.ui.control.Label
        Zerosequence0Label              matlab.ui.control.Label
        R0S1EditFieldT1                 matlab.ui.control.NumericEditField
        L0S1EditFieldT1                 matlab.ui.control.NumericEditField
        PositivesequenceLabel           matlab.ui.control.Label
        R1S1EditFieldT1                 matlab.ui.control.NumericEditField
        L1S1EditFieldT1                 matlab.ui.control.NumericEditField
        ThveninEqLabel                  matlab.ui.control.Label
        SubstationLabel                 matlab.ui.control.Label
        Circuit2dataPanel               matlab.ui.container.Panel
        TabGroup2_2                     matlab.ui.container.TabGroup
        VoltagesourceTab_2              matlab.ui.container.Tab
        RMSLLamplitudeVEditFieldLabel_2  matlab.ui.control.Label
        RMSLLamplitudeV2EditFieldT1     matlab.ui.control.NumericEditField
        FrequencyHzLabel_2              matlab.ui.control.Label
        FrequencyHz2EditFieldT1         matlab.ui.control.NumericEditField
        PhaseangledegLabel_2            matlab.ui.control.Label
        Phaseangledeg2EditFieldT1       matlab.ui.control.NumericEditField
        TstartsLabel_2                  matlab.ui.control.Label
        Tstarts2EditFieldT1             matlab.ui.control.NumericEditField
        TstopsLabel_2                   matlab.ui.control.Label
        Tstops2EditFieldT1              matlab.ui.control.NumericEditField
        ImpedancesTab_2                 matlab.ui.container.Tab
        RLabel_3                        matlab.ui.control.Label
        LmHLabel_3                      matlab.ui.control.Label
        CFLabel_2                       matlab.ui.control.Label
        GroundinggridLabel_2            matlab.ui.control.Label
        RGG2EditFieldT1                 matlab.ui.control.NumericEditField
        LGG2EditFieldT1                 matlab.ui.control.NumericEditField
        CGG2EditFieldT1                 matlab.ui.control.NumericEditField
        NeutralconnectionLabel_2        matlab.ui.control.Label
        RNC2EditFieldT1                 matlab.ui.control.NumericEditField
        LNC2EditFieldT1                 matlab.ui.control.NumericEditField
        CNC2EditFieldT1                 matlab.ui.control.NumericEditField
        LmHLabel_4                      matlab.ui.control.Label
        RLabel_4                        matlab.ui.control.Label
        Zerosequence0Label_2            matlab.ui.control.Label
        R0S2EditFieldT1                 matlab.ui.control.NumericEditField
        L0S2EditFieldT1                 matlab.ui.control.NumericEditField
        PositivesequenceLabel_2         matlab.ui.control.Label
        R1S2EditFieldT1                 matlab.ui.control.NumericEditField
        L1S2EditFieldT1                 matlab.ui.control.NumericEditField
        ThveninEqLabel_2                matlab.ui.control.Label
        SubstationLabel_2               matlab.ui.control.Label
        Terminal2Tab                    matlab.ui.container.Tab
        Circuit1dataPanel_2             matlab.ui.container.Panel
        TabGroup2_3                     matlab.ui.container.TabGroup
        VoltagesourceTab_3              matlab.ui.container.Tab
        RMSLLamplitudeVEditFieldLabel_3  matlab.ui.control.Label
        RMSLLamplitudeV1EditFieldT2     matlab.ui.control.NumericEditField
        FrequencyHzLabel_3              matlab.ui.control.Label
        FrequencyHz1EditFieldT2         matlab.ui.control.NumericEditField
        PhaseangledegLabel_3            matlab.ui.control.Label
        Phaseangledeg1EditFieldT2       matlab.ui.control.NumericEditField
        TstartsLabel_3                  matlab.ui.control.Label
        Tstarts1EditFieldT2             matlab.ui.control.NumericEditField
        TstopsLabel_3                   matlab.ui.control.Label
        Tstops1EditFieldT2              matlab.ui.control.NumericEditField
        ImpedancesTab_3                 matlab.ui.container.Tab
        RLabel_5                        matlab.ui.control.Label
        LmHLabel_5                      matlab.ui.control.Label
        CFLabel_3                       matlab.ui.control.Label
        GroundinggridLabel_3            matlab.ui.control.Label
        RGG1EditFieldT2                 matlab.ui.control.NumericEditField
        LGG1EditFieldT2                 matlab.ui.control.NumericEditField
        CGG1EditFieldT2                 matlab.ui.control.NumericEditField
        NeutralconnectionLabel_3        matlab.ui.control.Label
        RNC1EditFieldT2                 matlab.ui.control.NumericEditField
        LNC1EditFieldT2                 matlab.ui.control.NumericEditField
        CNC1EditFieldT2                 matlab.ui.control.NumericEditField
        LmHLabel_6                      matlab.ui.control.Label
        RLabel_6                        matlab.ui.control.Label
        Zerosequence0Label_3            matlab.ui.control.Label
        R0S1EditFieldT2                 matlab.ui.control.NumericEditField
        L0S1EditFieldT2                 matlab.ui.control.NumericEditField
        PositivesequenceLabel_3         matlab.ui.control.Label
        R1S1EditFieldT2                 matlab.ui.control.NumericEditField
        L1S1EditFieldT2                 matlab.ui.control.NumericEditField
        ThveninEqLabel_3                matlab.ui.control.Label
        SubstationLabel_3               matlab.ui.control.Label
        Circuit2dataPanel_2             matlab.ui.container.Panel
        TabGroup2_4                     matlab.ui.container.TabGroup
        VoltagesourceTab_4              matlab.ui.container.Tab
        RMSLLamplitudeVEditFieldLabel_4  matlab.ui.control.Label
        RMSLLamplitudeV2EditFieldT2     matlab.ui.control.NumericEditField
        FrequencyHzLabel_4              matlab.ui.control.Label
        FrequencyHz2EditFieldT2         matlab.ui.control.NumericEditField
        PhaseangledegLabel_4            matlab.ui.control.Label
        Phaseangledeg2EditFieldT2       matlab.ui.control.NumericEditField
        TstartsLabel_4                  matlab.ui.control.Label
        Tstarts2EditFieldT2             matlab.ui.control.NumericEditField
        TstopsLabel_4                   matlab.ui.control.Label
        Tstops2EditFieldT2              matlab.ui.control.NumericEditField
        ImpedancesTab_4                 matlab.ui.container.Tab
        RLabel_7                        matlab.ui.control.Label
        LmHLabel_7                      matlab.ui.control.Label
        CFLabel_4                       matlab.ui.control.Label
        GroundinggridLabel_4            matlab.ui.control.Label
        RGG2EditFieldT2                 matlab.ui.control.NumericEditField
        LGG2EditFieldT2                 matlab.ui.control.NumericEditField
        CGG2EditFieldT2                 matlab.ui.control.NumericEditField
        NeutralconnectionLabel_4        matlab.ui.control.Label
        RNC2EditFieldT2                 matlab.ui.control.NumericEditField
        LNC2EditFieldT2                 matlab.ui.control.NumericEditField
        CNC2EditFieldT2                 matlab.ui.control.NumericEditField
        LmHLabel_8                      matlab.ui.control.Label
        RLabel_8                        matlab.ui.control.Label
        Zerosequence0Label_4            matlab.ui.control.Label
        R0S2EditFieldT2                 matlab.ui.control.NumericEditField
        L0S2EditFieldT2                 matlab.ui.control.NumericEditField
        PositivesequenceLabel_4         matlab.ui.control.Label
        R1S2EditFieldT2                 matlab.ui.control.NumericEditField
        L1S2EditFieldT2                 matlab.ui.control.NumericEditField
        ThveninEqLabel_4                matlab.ui.control.Label
        SubstationLabel_4               matlab.ui.control.Label
        SoilresistivityTab              matlab.ui.container.Tab
        UITableSoilRes                  matlab.ui.control.Table
        DefaultsoilresistivitymLabel    matlab.ui.control.Label
        DefaultSoilResEditField         matlab.ui.control.NumericEditField
        SpecifyindividualsectionsLabel_2  matlab.ui.control.Label
        btnLoadSoilRes                  matlab.ui.control.Button
        PlotSoilResButton               matlab.ui.control.Button
        UIAxesSoilRes                   matlab.ui.control.UIAxes
        GroundimpTab                    matlab.ui.container.Tab
        UITableGroundImp                matlab.ui.control.Table
        SpecifyindividualsectionsLabel_3  matlab.ui.control.Label
        btnLoadGroundImp                matlab.ui.control.Button
        PlotGroundImpButton             matlab.ui.control.Button
        RLabel_9                        matlab.ui.control.Label
        LmHLabel_9                      matlab.ui.control.Label
        CFLabel_5                       matlab.ui.control.Label
        DefaultgroundimpedanceLabel     matlab.ui.control.Label
        GroundImpREditField             matlab.ui.control.NumericEditField
        GroundImpLEditField             matlab.ui.control.NumericEditField
        GroundImpCEditField             matlab.ui.control.NumericEditField
        TowersLabel                     matlab.ui.control.Label
        UIAxesGroundImp                 matlab.ui.control.UIAxes
        FaultmodelTab                   matlab.ui.container.Tab
        FaultedcircuitButtonGroup       matlab.ui.container.ButtonGroup
        Circuit1Button                  matlab.ui.control.RadioButton
        Circuit2Button                  matlab.ui.control.RadioButton
        PhasetophaseresistanceEditFieldLabel  matlab.ui.control.Label
        PhasetophaseresistanceEditField  matlab.ui.control.NumericEditField
        PhasetogroundresistanceEditFieldLabel  matlab.ui.control.Label
        PhasetogroundresistanceEditField  matlab.ui.control.NumericEditField
        FaultincidenceangleLabel        matlab.ui.control.Label
        FaultincidenceangleSpinner      matlab.ui.control.Spinner
        FaulttypeFTYPEButtonGroup       matlab.ui.container.ButtonGroup
        AGButton                        matlab.ui.control.RadioButton
        BGButton                        matlab.ui.control.RadioButton
        CGButton                        matlab.ui.control.RadioButton
        ABButton                        matlab.ui.control.RadioButton
        BCButton                        matlab.ui.control.RadioButton
        CAButton                        matlab.ui.control.RadioButton
        ABGButton                       matlab.ui.control.RadioButton
        BCGButton                       matlab.ui.control.RadioButton
        CAGButton                       matlab.ui.control.RadioButton
        ABCButton                       matlab.ui.control.RadioButton
        ABCGButton                      matlab.ui.control.RadioButton
        UITableFaultSec                 matlab.ui.control.Table
        SpecifyfaultedsectionsLabel     matlab.ui.control.Label
        CyclesbeforefaultSpinnerLabel   matlab.ui.control.Label
        CyclesbeforefaultSpinner        matlab.ui.control.Spinner
        OutputsTab                      matlab.ui.container.Tab
        Terminal1phasecurrentsCheckBox  matlab.ui.control.CheckBox
        RequestoutputsLabel             matlab.ui.control.Label
        Terminal1phasevoltagesCheckBox  matlab.ui.control.CheckBox
        Terminal2phasevoltagesCheckBox  matlab.ui.control.CheckBox
        Terminal2phasecurrentsCheckBox  matlab.ui.control.CheckBox
        FaultbranchphasecurrentsCheckBox  matlab.ui.control.CheckBox
        FaultbranchphasevoltagesCheckBox  matlab.ui.control.CheckBox
        ShieldwirereturncurrentsCheckBox  matlab.ui.control.CheckBox
        TowertogroundvoltagesCheckBox   matlab.ui.control.CheckBox
        TowertogroundcurrentsCheckBox   matlab.ui.control.CheckBox
        UITableTow2Ground               matlab.ui.control.Table
        SpecifysectionsLabel            matlab.ui.control.Label
        SpecifysectionsLabel_2          matlab.ui.control.Label
        UITableSWCurr                   matlab.ui.control.Table
        OrsectionsbeforeandafterfaultTowShuntParamCheckBox  matlab.ui.control.CheckBox
        SpinnerTow2Ground               matlab.ui.control.Spinner
        SpinnerSWCurr                   matlab.ui.control.Spinner
        OrsectionsbeforeandafterfaultShWireCurrCheckBox  matlab.ui.control.CheckBox
        RunTab                          matlab.ui.container.Tab
        MakeLCCfilesButton              matlab.ui.control.Button
        ConsoleTextAreaLabel            matlab.ui.control.Label
        ConsoleTextArea                 matlab.ui.control.TextArea
        MakemainATPfilesButton          matlab.ui.control.Button
        RunATPsolverButton              matlab.ui.control.Button
        StepbystepprocessLabel          matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        SavedataButton                  matlab.ui.control.Button
        LoadfromfileButton              matlab.ui.control.Button
        ConductordatabaseButton         matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
    end

    
    properties (Access = private)
        attSetApp % Dialog box app
    end
    
    methods (Access = public)
        
        function results = valIfTrue(app,test,valtrue,valfalse)
            results=[];
            if test
                results=valtrue;
            else
                results=valfalse;
            end
        end
        
        function [out] = getAttSet(app,sec)
            
            defattset=str2double(app.DefaultattributesetDropDown.Value);
            
            attsetmap=app.UITableAttSetSec.Data;
            attsetmap(any(cellfun(@isempty,attsetmap),2),:) = [];
            
            out=defattset;
            
            if isempty(attsetmap)
                return;
            end
            
            attsetmap=str2double(attsetmap);
            attsetmap=attsetmap(find(attsetmap(attsetmap(:,2)>=attsetmap(:,1))),:);
            
            for k=1:size(attsetmap,1)
                if sec >=attsetmap(k,1) && sec <= attsetmap(k,2)
                    out=attsetmap(k,3);
                end
            end
            
        end
        
        function [out] = getAttSetData(app,attset)
            attsetdata=app.UITableAttSets.Data(attset);
            attsetdata=jsondecode(char(attsetdata));
            
            if ~strcmp(attsetdata,'undefined')
                out=struct2cell(attsetdata)';
            else
                out=attsetdata;
            end
            
        end
        
        function [Rout, Rin, Rdc, TD] = getConductorData(app,condID)
            Rout=[];Rin=[];Rdc=[];TD=[];
            [wdir,~,~] = fileparts(mfilename('fullpath'));
            fname = 'condudb.mat';
            try
                load(fullfile([wdir '\assets'],fname),'condudb_data');
            catch
                f = msgbox('Conductor database app not found!','You lose, fella.','error');
                return;
            end
            
            
            idx = find(contains(condudb_data(:,1),condID));
            
            Rout=100*cell2mat(condudb_data(idx,2)); %radius in centimeters
            Rin=100*cell2mat(condudb_data(idx,3)); %radius in centimeters
            Rdc=cell2mat(condudb_data(idx,4));
            TD=(Rout-Rin)/(2*Rout);
        end
        
        function [out] = getLineModel(app)
            out=valIfTrue(app,app.PiButton.Value==1,'pi',valIfTrue(app,app.BergeronButton.Value==1,'bergeron','jmarti'));
        end
        
        function [out] = getLineModelParams(app)
            out=struct;
            out.frequency=double(app.LCCfrequencyHzEditField.Value);
            if strcmp(getLineModel(app),'jmarti')
                out.decades=double(app.DecadesSpinner.Value);
                out.points_per_decade=double(app.PointsDecadeSpinner.Value);
                out.freq_matrix=double(app.FreqmatrixHzEditField.Value);
                out.freq_ss=double(app.FreqSSHzEditField.Value);
            end
        end
        
        function [nphases] = getNumPhases(app)
            nphases=valIfTrue(app,app.SinglecircuitButton.Value==1,3,6);
        end
        
        function [nsw] = getNumShieldWires(app)
            nsw=valIfTrue(app,app.NoneSWButton.Value==1,0,valIfTrue(app,app.SingleSWButton.Value==1,1,2));
        end
        
        function [out] = getSoilRes(app,sec)
            out=cell2mat(app.UITableSoilRes.Data(sec));
        end
        
        function [out] = getSpanLen(app,sec)
            out=cell2mat(app.UITableSL.Data(sec));
        end
        
        function [out] = getTranspositionScheme(app)
            
            out=valIfTrue(app,app.UntransposedButton.Value==1,0,valIfTrue(app,app.L3L3L3Button.Value==1,1,2));
            % 0 = untransposed
            % 1 = L/3 L/3 L/3
            % 2 = L/6 L/3 L/3 L/6
        end
        
        function [out] = isTranspositionTower(app,sec)
            out=false;
            tscheme=getTranspositionScheme(app);
            
            if tscheme==0
                return;
            end
            % 0 = untransposed
            % 1 = L/3 L/3 L/3
            % 2 = L/6 L/3 L/3 L/6
            allspans=cell2mat(app.UITableSL.Data);
            numsec=app.NumberofsectionsSpinner.Value;
            L=cumsum(allspans)';
            totalLen=L(end);
            
            if tscheme==1
                Ltransp=cumsum([totalLen/3 totalLen/3 totalLen/3]);
                if numsec==3
                    out=ismember(sec,1:2);
                    return;
                end
            end
            
            if tscheme==2
                Ltransp=cumsum([totalLen/6 totalLen/3 totalLen/3 totalLen/6]);
                if numsec==4
                    out=ismember(sec,1:3);
                    return;
                end
            end
            
            transp_idx = interp1(L,1:length(L),Ltransp,'nearest','extrap');
            
            out=ismember(sec,transp_idx(1:end-1));
            
            
        end
        
        function [out] = makeLCCC(app,sec)
            
            lmodel=getLineModel(app);
            
            % header
            out='BEGIN NEW DATA CASE\n';
            out=strcat(out,valIfTrue(app,strcmp(lmodel,'jmarti'),'JMARTI SETUP\n','LINE CONSTANTS\n'));
            out=strcat(out,'$ERASE\n');
            
            % branch
            out=strcat(out,writeLCCBranchCard(app,sec),'\n');
            if strcmp(lmodel,'jmarti')
                out=strcat(out,'LINE CONSTANTS\n');
            end
            out=strcat(out,'METRIC\n');
            out=strcat(out,'C >SKIN>RESIS  >I>REACT  >DIAM   >HORIZ  >VTOWER >VMID   >SEPAR  >ALPHA>      NB\n');


            % conductor
            all_conds=parseAttSetData(app,sec);
            nc=size(all_conds,1);
            for i=1:nc
                con=all_conds(i,:);
                out=strcat(out,writeLCCConductorCard(app,con),'\n');
            end
            out=strcat(out,'BLANK CARD ENDING CONDUCTOR CARDS\n');
            
            % frequency
            if strcmp(lmodel,'pi')
                out=strcat(out,writePIFreqCard(app,sec),'\n');
            elseif strcmp(lmodel,'bergeron')
                out=strcat(out,writeBergFreqCard(app,sec),'\n');
            elseif strcmp(lmodel,'jmarti')
                out=strcat(out,writeJMartiFreqCard(app,sec),'\n');
            end
            
            % footer
            out=strcat(out,'BEGIN NEW DATA CASE\n');
            out=strcat(out,'BLANK CARD\n');
            
            
        end
        
        function [strbranch] = writeLCCBranchCard(app,sec)
            
            num_nodes=getNumPhases(app)+getNumShieldWires(app);
            
            seq_in='ABC';
            seq_out=valIfTrue(app,isTranspositionTower(app,sec),'CAB','ABC');
            
            switch num_nodes
                case 3 % 1 circuit, no shield wire
                    strbranch=sprintf('BRANCH  1%04d%s1%04d%s1%04d%s1%04d%s1%04d%s1%04d%s', ...
                        sec-1,seq_in(1),sec,seq_out(1), ...
                        sec-1,seq_in(2),sec,seq_out(2), ...
                        sec-1,seq_in(3),sec,seq_out(3));
                    
                case 4 % 1 circuit, one shield wire
                    strbranch=sprintf('BRANCH  1%04d%s1%04d%s1%04d%s1%04d%s1%04d%s1%04d%s0%04d 0%04dM', ...
                        sec-1,seq_in(1),sec,seq_out(1), ...
                        sec-1,seq_in(2),sec,seq_out(2), ...
                        sec-1,seq_in(3),sec,seq_out(3), ...
                        sec-1,sec);
                    
                case 5 % 1 circuit, two shield wires
                    strbranch=sprintf('BRANCH  1%04d%s1%04d%s1%04d%s1%04d%s1%04d%s1%04d%s0%04d 0%04dM', ...
                        sec-1,seq_in(1),sec,seq_out(1), ...
                        sec-1,seq_in(2),sec,seq_out(2), ...
                        sec-1,seq_in(3),sec,seq_out(3), ...
                        sec-1,sec);
                    
                case 6 % 2 circuits, no shield wire
                    strbranch=sprintf('BRANCH  1%04d%s1%04d%s1%04d%s1%04d%s1%04d%s1%04d%s2%04d%s2%04d%s2%04d%s2%04d%s2%04d%s2%04d%s', ...
                        sec-1,seq_in(1),sec,seq_out(1), ...
                        sec-1,seq_in(2),sec,seq_out(2), ...
                        sec-1,seq_in(3),sec,seq_out(3), ...
                        sec-1,seq_in(1),sec,seq_out(1), ...
                        sec-1,seq_in(2),sec,seq_out(2), ...
                        sec-1,seq_in(3),sec,seq_out(3));
                    
                case 7 % 2 circuits, one shield wire
                    strbranch=sprintf('BRANCH  1%04d%s1%04d%s1%04d%s1%04d%s1%04d%s1%04d%s2%04d%s2%04d%s2%04d%s2%04d%s2%04d%s2%04d%s\nBRANCH  0%04d 0%04dM', ...
                        sec-1,seq_in(1),sec,seq_out(1), ...
                        sec-1,seq_in(2),sec,seq_out(2), ...
                        sec-1,seq_in(3),sec,seq_out(3), ...
                        sec-1,seq_in(1),sec,seq_out(1), ...
                        sec-1,seq_in(2),sec,seq_out(2), ...
                        sec-1,seq_in(3),sec,seq_out(3), ...
                        sec-1,sec);
                    
                case 8 % 2 circuits, two shield wires
                    strbranch=sprintf('BRANCH  1%04d%s1%04d%s1%04d%s1%04d%s1%04d%s1%04d%s2%04d%s2%04d%s2%04d%s2%04d%s2%04d%s2%04d%s\nBRANCH  0%04d 0%04dM', ...
                        sec-1,seq_in(1),sec,seq_out(1), ...
                        sec-1,seq_in(2),sec,seq_out(2), ...
                        sec-1,seq_in(3),sec,seq_out(3), ...
                        sec-1,seq_in(1),sec,seq_out(1), ...
                        sec-1,seq_in(2),sec,seq_out(2), ...
                        sec-1,seq_in(3),sec,seq_out(3), ...
                        sec-1,sec);
            end
            
        end
        
        function [out] = parseAttSetData(app,sec)
            
            attnum=getAttSet(app,sec);
            attdata=getAttSetData(app,attnum);
            
            nph=getNumPhases(app);
            nsw=getNumShieldWires(app);
            
            KTYPE=4; %see rule book, sectn 9, vol 2, pg 9-13
            
            out=[];
            for i=1:nph
                [Rout, Rin, Rdc, TD] = getConductorData(app,char(attdata(i,1)));
                nc=str2double(attdata(i,2));
                s=str2double(attdata(i,3));
                x0=str2double(attdata(i,4));
                y0=str2double(attdata(i,5));
                tmpcoords=calcConductorCoords(app,x0,y0,nc,s);
                for k=1:nc
                    thisrow=[i TD Rdc KTYPE 2*Rout tmpcoords(k,1) tmpcoords(k,2) tmpcoords(k,2)];
                    out=vertcat(out,thisrow);
                end
            end
            
            if nsw>0
                for i=1:nsw
                    [Rout, Rin, Rdc, TD] = getConductorData(app,char(attdata(nph+i,1)));
                    nc=str2double(attdata(nph+i,2));
                    s=str2double(attdata(nph+i,3));
                    x0=str2double(attdata(nph+i,4));
                    y0=str2double(attdata(nph+i,5));
                    tmpcoords=calcConductorCoords(app,x0,y0,nc,s);
                    for k=1:nc
                        thisrow=[nph+1 TD Rdc KTYPE 2*Rout tmpcoords(k,1) tmpcoords(k,2) tmpcoords(k,2)];
                        out=vertcat(out,thisrow);
                    end
                end
            end
            
        end
        
        function [out] = writeLCCConductorCard(app,con)
            out=blanks(80);
            %         1         2         3         4         5         6         7         8
            %12345678901234567890123456789012345678901234567890123456789012345678901234567890
            %C >SKIN>RESIS  >I>REACT  >DIAM   >HORIZ  >VTOWER >VMID   >SEPAR  >ALPHA>      NB
            out(1:3)=sprintf('%3d',con(1));
            out(4:8)=modprin(app,5,con(2));
            out(9:16)=modprin(app,8,con(3));
            out(17:18)=sprintf('%2d',con(4));
            out(27:34)=modprin(app,8,con(5));
            out(35:42)=modprin(app,8,con(6));
            out(43:50)=modprin(app,8,con(7));
            out(51:58)=modprin(app,8,con(8));
        end
        
        function [out] = writePIFreqCard(app,sec)
            out=blanks(80);
            rho=getSoilRes(app,sec);
            lineparams=getLineModelParams(app);
            f=lineparams.frequency;
            L=getSpanLen(app,sec)/1000;
            ruler='C RHO  >FMATRIX  >          >ICPRN> IZPRN>I>ALONG  > IPI>IM          MOIF\n';
            out(1:8)=modprin(app,8,rho);
            out(9:18)=modprin(app,10,f);
            out(44)=sprintf('%d',1);
            out(45:52)=modprin(app,8,L);
            out(67:68)=sprintf('%d',44);
            
            out=strcat(out,'\n','$PUNCH\n');
            out=strcat(out,'BLANK CARD ENDING FREQUENCY CARDS\n');
            out=strcat(out,'BLANK CARD ENDING LINE CONSTANT');
            out=strcat(ruler,out);
        end
        
        function [out] = writeBergFreqCard(app,sec)
            out=blanks(80);
            rho=getSoilRes(app,sec);
            lineparams=getLineModelParams(app);
            f=lineparams.frequency;
            L=getSpanLen(app,sec)/1000;
            ruler='C RHO  >FMATRIX  >          >ICPRN> IZPRN>I>ALONG  > IPI>IM          MOIF\n';
            out(1:8)=modprin(app,8,rho);
            out(9:18)=modprin(app,10,f);
            out(45:52)=modprin(app,8,L);
            out(70:72)='1-9';
            
            out=strcat(out,'\n','BLANK CARD ENDING FREQUENCY CARDS\n');
            out=strcat(out,'$PUNCH\n');
            out=strcat(out,'BLANK CARD ENDING LINE CONSTANT');
            out=strcat(ruler,out);
        end
        
        function [out] = writeJMartiFreqCard(app,sec)
            out1=blanks(80);out2=blanks(80);out3=blanks(80);
            rho=getSoilRes(app,sec);
            lineparams=getLineModelParams(app);
            f=lineparams.frequency;
            dec=lineparams.decades;
            pdec=lineparams.points_per_decade;
            fmat=lineparams.freq_matrix;
            fss=lineparams.freq_ss;
            L=getSpanLen(app,sec)/1000;
            ruler='C RHO  >FMATRIX  >          >ICPRN> IZPRN>I>ALONG  > IPI>IM          MOIF\n';
            
            out1(1:8)=modprin(app,8,rho);
            out1(9:18)=modprin(app,10,fmat);
            out1(45:52)=modprin(app,8,L);
            out1(70:72)='1-9';
            
            out2(1:8)=modprin(app,8,rho);
            out2(9:18)=modprin(app,10,fss);
            out2(45:52)=modprin(app,8,L);
            out2(70:72)='1-9';
            
            
            out3(1:8)=modprin(app,8,rho);
            out3(9:18)=modprin(app,10,f);
            out3(45:52)=modprin(app,8,L);
            out3(61:62)=sprintf('%2d',dec);
            out3(64:65)=sprintf('%2d',pdec);
            out3(70:72)='1-9';
            
            out=strcat(ruler, out1,'\n',out2,'\n',out3,'\n');
            
            out=strcat(out,'BLANK CARD ENDING FREQUENCY CARDS\n');
            out=strcat(out,'BLANK CARD ENDING LINE CONSTANT\n');
            out=strcat(out,'DEFAULT\n');
            out=strcat(out,'$PUNCH\n');
            out=strcat(out,'BLANK CARD ENDING JMARTI SETUP');
            
        end
        
        function [out] = modprin(app,len,num)
            
            y=prin(app,['%' sprintf('%d',len) 'V'], num);
            
            k = strfind(y,'e');
            
            if ~isempty(k)
                
                y=prin(app,['%' sprintf('%d',len) 'v'], num);
                k = strfind(y,'e');
                substr=y(1:k-1);
                kk=strfind(substr,'.');
                
                if ~isempty(kk)
                    num_after_dot=length(substr(kk+1:end));
                    pow=y(k+1:end);
                    newpow=num2str(str2num(pow)-num_after_dot + 1);
                    substr=erase(substr,'.');
                    substr = substr(1:end-1);
                    abspow = abs(str2num(pow));
                    absnewpow = abs(str2num(newpow));
                    if (abspow < 10)
                        if(absnewpow > 9)
                            substr = substr(1:end-1);
                            newpow=num2str(str2num(pow)-num_after_dot +2);
                        end
                    end
                end
                y = [substr '.e' newpow];
            end
            
            if str2num(y) > 0 && str2num(y) < 1 && strfind(y,'.') == 1
                y = ['0' y(1:end-1)];
            elseif str2num(y) < 0 && str2num(y) > -1 && strfind(y,'.') == 2
                y = ['-0' y(2:end-1)];
            end
            
            if len ~=length(y)
                y = [' ' y];
            end


            out=y;
            
        end
        
        function s = prin(app,fmt,varargin)
            %
            % prin.m:  An alternative to sprintf() & fprintf() - version 16Feb17
            %          Calls Pftoa.m
            %
            % Calling sequence: ------------------------------
            % s = prin(app,FormatString,OptionalArguments)
            %               or
            % s = prin(app,FileID,FormatString,OptionalArguments)
            %
            % FormatString '%nV' uses exactly n characters
            % FormatString '%nW' uses at most n characters
            % Formats v,w are similar except that decimal points are not counted
            %
            % For a more complete description, type "prin" at the command prompt.
            %     (This will open prin.pdf)
            %
            % Author:  Paul Mennen (paul@mennen.org)
            %          Copyright (c) 2017, Paul Mennen
            
            s = '';
            if ~nargin % open the documentation file if called without arguments
                if exist('s') open(feval('which','prin.pdf')); return; end;
            end;
            if iscell(fmt) & nargin==1                                  % special form used to set pretty print defaults
                if length(fmt) setappdata(0,'prin',fmt);                  % set new defaults
                else           rmappdata(0,'prin');                       % revert to defaults
                end;
                return;
            end;
            if isnumeric(fmt)                                           % here if the first argument is numeric?
                if nargin==1                                              % single numeric argument special case (array pretty print)
                    f = '%+7W  ';  hdr = '%3d: ';  epr = 10;                % default format, header format, and number of entries per row
                    v = getappdata(0,'prin');  lv = length(v);              % get f,epr,hdr from prin appdata if it exists
                    if lv  f=v{1};  if lv>1 epr=v{2}; end;  if lv>2 hdr=v{3}; end;
                        if isnumeric(f) f = sprintf('%%+%dW  ',f); end;
                    end;
                    f = sprintf('%s%d{%s}\\n',hdr,epr,f);                    % build prin format string
                    v = fmt(:);  n = length(v);  pad = ceil(n/epr)*epr - n;  % number of pad elements to make a multiple of 10
                    c = (n+pad)/epr;  head = epr*(1:c) - epr + 1;            % number of columns to print, header row
                    v = [head; reshape([v; zeros(pad,1)],epr,c)];  v = v(:); % reshape input
                    s = prin(app,f,v(1:end-pad));
                    return;
                end;
                s = prin(app,varargin{:});                                    % come here if the 1st arguement a FileID or a file name
                if length(fmt)>1                                          % is the first argument a file name?
                    if fmt(1)>0 p = 'at'; else p = 'wt'; fmt=-fmt; end;  % yes, come here (+/- = append/write)
                    fmt = char(fmt);  nc = fmt(1)==' ';  if nc fmt(1)=''; end; % don't close if space in filename
                    fmt = fopen(fmt,p); fwrite(fmt,s);
                    if nc setappdata(0,'FIDp',fmt); else fclose(fmt); end;
                else v = version;                                           % no, come here if it's a FileID
                    if v(1)=='6' & (fmt==1 | fmt==2)                       % fwrite to standard out doesn't
                        fprintf(fmt,strrep(strrep(s,'%','%%'),'\','\\')); % work in ver 6.1
                    elseif fmt<1 f = getappdata(0,'FIDp');  fwrite(f,s);  if fmt<0 fclose(f); end;
                    else fwrite(fmt,s);
                    end;
                end;
                return;
            end;
            fmt = strrep(strrep(fmt,'\{','\173'),'\}','\175'); % replace \{ and \} with octal codes
            p = find(fmt=='{');                     % search for last repeat block
            while length(p)                         % keep going until all {} repeat blocks are expanded
                p = p(end);  v = p;                   % p points to left bracket, v points to repeat count
                q = find(fmt(p:end)=='}');
                if isempty(q) disp('unmatched { in format string'); return; end;
                q = q(1)+p;                           % point to character after matching end brace
                bb = fmt(p+1:q-2);                    % contents between the braces
                r = fmt(max(1,p-1))-48;               % get single digit repeat count
                if r>=0 & r<=9                        % valid repeat count.
                    v = v-1;
                    if p>2 r2 = fmt(p-2)-48;                                 % is this a 2 digit repeat count?
                        if r2>=0 & r2<=9  v=v-1; r=r+10*r2; end;          % if yes, compute new repeat count
                    end;
                    bb = repmat(bb,1,r);                                     % replicate the repeat block
                    e = find(bb=='!');                                       % find all the explanation points
                    if length(e) bb([e e(end)+1:end]) = []; end;             % remove all ! & text after the last !
                    fmt = [fmt(1:v-1) bb fmt(q:end)];                        % insert the replicated text
                else if length(find(bb=='%'))~=1 | r==46 | r==47              % no valid repeat count
                        fmt = [fmt(1:p-1) '\173' bb '\175' fmt(q:end)];     % assume normal text (super/subscripts)
                    else fmt = [fmt(1:p-1) 'LbRaCe' bb 'RbRaCe' fmt(q:end)]; % assume vector format (exactly one %)
                    end;
                end;
                p = find(fmt=='{');                   % cycle thru all repeat blocks (back to front order)
            end;  % end while length(p)
            fmt = strrep(strrep(fmt,'LbRaCe','{'),'RbRaCe','}'); % change back to braces around the delimiters
            p = find(fmt=='%');     % search for '%' format codes
            q = find(diff(p)==1);   % search for '%%'
            while length(q) p(q(1):q(1)+1) = []; q = find(diff(p)==1); end; % remove all '%%' from the list
            n = length(p);          % number of format codes
            nn = length(fmt);       % length of fmt argument
            codes = 'vVwWscdiouxXfeEgG';
            c = cell(1,n+1);        % will contain all the characters of fmt with format strings removed
            f = cell(1,n);          % will contain all the format strings contain in fmt
            ws = zeros(1,n);        % used to save the code index associated with f{n}
            g=f; d=f; e=ws;         % f,d has format text surrounding bracketed format string f{k}. e has position of '!'
            cb = 1;                 % point to beginning of next c{} string
            for k=1:n               % extract the n format strings into f{1} to f{n}
                q = p(k);  w = '';    % q is the location of the % sign for this format string
                c{k} = fmt(cb:q-1);   % save format text between f{k-1} and f{k} (not including format strings)
                lb = find(c{k}=='{'); % pointer to left bracket
                if length(lb)
                    lb = lb(1);
                    g{k} = sprintf(c{k}(lb+1:end));  % remove first part of bracketed format from c and put it in g
                    c{k} = c{k}(1:lb-1);
                end;
                for j=q+1:nn         % search for the format code (i.e. the end of the format string)
                    w = find(codes==fmt(j));
                    if length(w) break; end;
                end;
                if isempty(w) disp(sprintf('sprint: Unknown format code starting with %s',fmt(q:end))); return; end;
                ws(k) = w;               % save format code index
                f{k} = ['%' fmt(q+1:j)]; % save format string from the '%' to the format code
                cb = j+1;
                if length(lb)
                    w = find(fmt(cb:end)=='}');
                    if isempty(w) disp('unmatched { in format string'); return; end;
                    w = w(1) + cb;                  % point to character after the matching right brace
                    d{k} = sprintf(fmt(cb:w-2));    % the remaining portion of the bracket vecotr format
                    ek = find(d{k}=='!');           % search for delimiter character
                    if length(ek) d{k} = strrep(strrep(d{k},'!row','! ~, '),'!col','! ~; ');
                        ek=ek(1); e(k)=ek; d{k}(ek)=[];    % if found, record its position and remove it
                    else          e(k) = length(d{k})+1;             % nonzero indicates a bracket vector format
                    end;
                    cb = w;
                end;
            end;  % end for k=1:n
            if cb<=nn c{n+1} = sprintf(fmt(cb:nn)); end;         % save format text that follows the last format string
            for k=1:n c{k} = sprintf(c{k}); end;                 % sprintf conversions (e.g. \t to tab character)
            s = c{1};  q = 1;                                    % q points to the next format string
            na = length(varargin);  k = 0;                       % k points to the argument being processed
            if na & ~n disp('prin(app,) warning: No format codes. Variables not converted.'); na=0; end;
            while k < na                                         % cycle thru all the arguments
                k = k+1;  arg = varargin{k};                       % get next argument
                while iscell(arg)
                    varargin = [arg(:)' varargin(k+1:end)];          % if it's a cell array, append it to the
                    k=1;  arg = varargin{1};  na = length(varargin); % front of the remaining arguments
                end;
                if e(q)>0
                    arg = arg(:);  nb = length(arg);                 % bracket vector format comes here
                    for j=1:nb                                       % loop thru each number in the vector
                        ft = Pftoa(app,f{q},arg(j));                       % convert the next number in the vector to ascii
                        if j<nb s = [s g{q} ft d{q}];                  % surround the number with left and right text (g,d)
                        else    s = [s g{q} ft d{q}(1:e(q)-1) c{q+1}]; % for last vector element, don't include the delimiter
                        end;
                    end;
                    q = q + 1;                                       % advance to next format string
                    if q>n & k<na q=1; s=[s c{1}]; end;              % reuse the format string from the beginning
                else                                               % here for not a bracket vector format
                    if ws(q)==5 s = [s sprintf(f{q},arg) c{q+1}];    % here for %s format
                        q = q + 1;                           % advance to next format string
                        if q>n & k<na q=1; s=[s c{1}]; end;  % reuse the format string
                    else                                             % here for all other formats (except %s)
                        arg = arg(:);  nb = length(arg);
                        for j=1:nb                                     % cycle thru each element of next argument
                            s = [s Pftoa(app,f{q},arg(j)) c{q+1}];           % conversion with Pftoa
                            q = q + 1;                                   % advance to next format string
                            if q>n & (j<nb | k<na) q=1; s=[s c{1}]; end; % reuse the format string from the beginning
                        end;    % end for j=1:nb
                    end;      % end if ws(q)==5
                end;        % end if e(k)>=0
            end;          % end while k <= na
            pr = findstr(s,' ~, '); pc = findstr(s,' ~; ');      % find the cell array row & column separators
            n = length(pr) + length(pc);
            if ~n return; end;                                   % return if cell seperators were not used
            pp = sort([pr pc+.1 length(s)+1]);  p = round(pp);   % fractional part indicates column marker
            row = 1;  col = 1;  c = s;  s = [];  p2 = -3;
            for k = 1:n+1                                        % break the output string into a cell array
                p1 = p2;  p2 = p(k);                               % p1/p2 are previous and current separator locations
                s{row,col} = c(p1+4:p2-1);                         % exclude the separator characters
                if p2==pp(k) col=col+1; else col=1; row=row+1; end; % advance column or row as appropriate
            end;
        end
        
        function s = Pftoa(app,fmtstr,val) % floating point to ascii convertion - called by prin.m
            %
            % Pftoa.m:  Alternative number conversion formatting - version 01Jan17
            % Author:   Paul Mennen (paul@mennen.org)
            %           Copyright (c) 2017, Paul Mennen
            %
            % function s = Pftoa(app,fmtstr,val)
            % returns a string representing the number val using the format specified by fmtstr
            %.
            % fmtstr: format description string
            % val:    the number to be converted to ascii
            %
            %
            % fmtstr in the form '%nV' --------------------------------------------
            % n: the field width
            % s: the string representation of x with the maximum resolution possible
            %    while using at exactly n characters.
            %
            % fmtstr in the form '%nv' ---------------------------------------------
            % n: the field width, not counting decimal point (since it's so skinny)
            % s: the string representation of x with the maximum resolution possible
            %    while using at exactly n+1 characters. (If a decimal point is not
            %    needed, then only n characters will be used).
            %
            % fmtstr in the form '%nW' ---------------------------------------------
            % n: the field width
            % s: the string representation of x with the maximum resolution possible
            %    while using at most n characters.
            %
            % fmtstr in the form '%nw' ---------------------------------------------
            % n: the field width, not counting decimal point (since it's so skinny)
            % s: the string representation of x with the maximum resolution possible
            %    while using at most n+1 characters.
            %
            % For any of the VWvw formats, if the field width is too small to allow
            % even one significant digit, then '*' is returned.
            
            % If the format code is not one of the four characters VWvw then use
            % the sprintf c conventions: s=sprintf(fmtstr,number);
            % e.g.  Pftoa(app,'%7.2f',value) is identical to sprintf('%7.2f',value).
            
            % Optional format modifiers are allowed between the % sign and the field width.
            % An optional modifier is one of the characters "+-jJkL". The + and - modifiers
            % control padding the output with blanks and the other four modifiers allow
            % the conversion of complex numbers. These modifies are described fully in
            % the prin.pdf help file.
            
            % BACKGROUND: ---------------------------------------------------------------
            % Pftoa(app,) expands on the number conversion capabilities of sprintf's d,f,e,g
            % conversion codes (which are identical to the c language conventions). These
            % formats are ideal for many situations, however the fact that these formats
            % will sometimes output more characters than the specified field width make
            % them inappropriate when used to generate a number that is displayed in a
            % fixed sized GUI element (such as an edit box) or in a table of numbers
            % arranged in fixed width columns. This motivated the invention of Pftoa's new
            % V and W formats. With the e & g formats one is often forced to specify a very
            % small number of signifcant digits since otherwise on the possibly rare
            % occations when the numbers are very big or very small an unintelligable
            % display is produced in the GUI, or the generated table becomes hopelessly
            % misallined. For example, suppose a column of numbers of width 8 characters
            % normally contains numbers that look something like 1.234567 but could
            % occationally contain a number such as 7.654321E-100. The best you could
            % do with a g format would be '%8.2g' which would produce the strings
            % 1.2 and 7.6E-100 which means the numbers we see most often are truncated
            % far more than necessary. Essentially with the e and g formats, you specify
            % the precision you want and you accept whatever size string is produced.
            % With the V and W formats, this is turned around. You specify the length
            % of the string you want, and Pftoa supplies as much precision as possible
            % with this constraint.
            %
            % For displaying columns of numbers (using a fixed spaced font) the V format
            % is best since it always outputs a character string of the specified length.
            % For example, the format string '%7V' will output seven characters. Never
            % less and never more.
            %
            % For displaying a number in an edit box the W format is best. For example
            % '%7W' will output at most seven characters, although it will output fewer
            % than 7 characters if this does not reduce the precision of the output.
            % For example, the integer "34" will be displayed with just two characters
            % (instead of padding with blanks like the V format does). Since the text
            % in an edit box is most often center aligned, this produces a more pleasing
            % result. Using a lower case w (i.e. the '%7w' format) behaves similarly
            % except that periods are not counted in the character count. This means
            % that if a decimal point is needed to represent the number, 8 characters
            % will be output and if a decimal point is not included in the representation
            % then only 7 characters are output. This is most useful when using
            % proportional width fonts. The period is not counted because the character
            % width of the period is small compared with the '0-9' digits. Actually
            % since most GUIs are rendered using proportially spaced fonts, the w format
            % is used more often than the W format.
            %
            if nargin~=3 disp('Calling sequence: resultString = Pftoa(app,formatString,val)'); return; end;
            
            fmtstr = deblank(fmtstr);                 % make sure format code is the last character
            fcode = fmtstr(end);  fc = upper(fcode);  % extract format code. Convert to upper case
            fw = fmtstr(2:end-1);                     % extract field width
            pad = 0;                                  % no final padding (+1/-1 = pad on left/right)
            if isempty(fw) fw = '7'; end;             % if field width is omited, use the default (7)
            mf = fw(1);  sp = ' '; lmf = lower(mf);   % get possible modifier
            if lmf=='k' mf=mf-1; lmf=lmf-1; sp=''; end; % k/K modifiers are more "Kompact" than j/J (no spaces)
            if lmf=='j'                               % is there a complex modifier?
                fw(1) = '%';  fw = [fw fcode];          % yes, create the format string without the modifier
                ival = imag(val);  rval = real(val);
                if mf=='J' | (rval*ival)~=0
                    if ival<0 pp = '-'; ival = -ival; else pp = '+'; end;
                    if rval==0 rval=abs(rval); end;
                    s = [Pftoa(app,fw,rval) sp pp sp Pftoa(app,fw,ival) 'i'];  % both real/imag parts
                elseif ival s = [Pftoa(app,fw,ival) 'i']; else s = Pftoa(app,fw,rval); % only need one part
                end;
                return;
            end;
            if fc~='W' & fc~='V'  s = sprintf(fmtstr,val); return; end; % use sprintf if format isn't v,V,w,W
            uc = fc==fcode;                           % upper case code (i.e. V or W)
            val = real(val);                          % ignore imaginary part
            if mf=='+' pad=1; elseif mf=='-' pad=-1; end;
            if pad fw = fw(2:end); if isempty(fw) fw = '7'; end; end;
            w = sscanf(fw,'%d'); v = w;               % get field width
            if ~w s = ''; return; end;                % zero field width returns an empty string
            if fc=='V' s = [blanks(v-1) '*']; else s = '*'; end;  ss = s;  neg = [];
            if     val==0     s = strrep(s,'*','0');
                
            elseif isnan(val) s = [blanks(length(s)-3) 'NaN']; if v<3 s=s(1:v); end;
            elseif isinf(val) if val>0 s = [blanks(length(s)-3) 'Inf'];  if v<3 s=s(1:v); end;
                else     s = [blanks(length(s)-4) '-Inf']; if v<4 s=s(1:v); end;
                end;
            else neg = val<0;
            end;
            if isempty(neg)  % special cases (0,Inf,Nan) come here
                if pad
                    if fc=='W'    p = v-length(s);  if p<1 return; end;
                        if pad>0 s = [blanks(p) s]; else s = [s blanks(p)]; end;
                    elseif pad<0  while s(1)==' ' s = [s(2:end) ' ']; end;
                    elseif val==0 s = ['0.' repmat('0',1,v-2)];
                    end;
                end;
                return;
            end;
            q = [6 0 1 1; 5 1 1 2; 4 0 3 3; 0 0 0 0; 3 0 4 4; 4 1 2 3; 5 2 0 2;   % v,w formats
                7 1 0 0; 6 2 0 1; 5 1 2 2; 0 0 0 0; 4 1 3 3; 5 2 1 2; 6 3 -1 1]; % V,W formats
            q = q(7*uc+min(find(abs(val) < [1e-99 1e-9 .01 10^(v-neg) 1e10 1e100 inf])),:);
            fp = v - q(1) - neg;                         % compute fp, the format precision
            if fp==-1 & uc fp=0; v=v+1; end;
            if fp<0 return; end;                         % not enough digits available
            if q(1) fmt = 'e'; else fmt = 'g'; end;      % select the e or g format
            if ~fp  q = q + [0,1,-1,-1]; end;            % e format sometimes removes the "."
            s = sprintf(sprintf('%%1.%d%c',fp,fmt),val); % convert to decimal string
            n = length(s);                               % length of result
            if n>3 & s(n-3)=='e'                         % is it a 2 digit exponent (for MAC)
                s = [s(1:n-2) '0' s(n-1:n)];               % change it to 3 digits
                n = n + 1;
            end;
            if q(1) q = [1:v-q(2) v+q(3):v+q(4)];        % here for e format
            else                                         % here for g format
                fdot = findstr('.',s);
                if length(fdot)
                    i = uc;  lz = 0;
                    if fdot==2 & s(1)=='0' | length(findstr('-0.',s))
                        i = i + 1;
                        lz = length(findstr('0.0',s));
                    end;
                    if i s = sprintf(sprintf('%%1.%dg',fp-i),val); % use one or two fewer digits
                        n = length(s);
                    end;
                    if lz s = strrep(s,'0.0','.0'); n=n-1; end;
                end;
                q = 1:min(~uc+v,n);
            end; % end if q(1)
            if max(q)>n s = ss; return; end;             % don't go over array bounds
            s = s(q);  n = length(s);
            if length([findstr(s,'0') findstr(s,'.') findstr(s,'-')]) == n % is there at least one nonzero digit?
                s = ss; return;                                              % return if not
            end;
            if fc=='V'
                p = w-length(s);                           % number of padding characters required
                isp = length(findstr('.',s));              % true if there is a period
                if ~uc p=p+isp; end;
                if p<=0 return; end;                       % no padding required
                if fmt=='e' s = [' ' s]; return; end;      % pad with blanks on left (p will be 1)
                if ~isp s=[s '.']; if uc p=p-1; end; end;  % if there is no period, add one before padding
                s = [s repmat('0',1,p)];                   % pad with zeros on the right
            elseif pad & fc=='W'
                p = v-length(s);  if p<1 return; end;
                if pad>0 s = [blanks(p) s]; else s = [s blanks(p)]; end;
            end;
        end
        
        function [out] = calcConductorCoords(app,x0,y0,nc,s)
            
            switch nc
                case 1
                    out=[x0 y0];
                case 2
                    a=valIfTrue(app,s==0,.2,s/2);
                    out=[x0-a y0; x0+a y0];
                case 4
                    a=valIfTrue(app,s==0,.2,s/2);
                    out=[x0-a y0-a; x0-a y0+a; x0+a y0-a; x0+a y0+a];
            end
            
        end
        
        function out = getProjectID(app)
            out=app.ProjectIDEditField.Value;
        end
        
        function out = getProjectDesc(app)
            out=app.ProjectdescriptionTextArea.Value;
            out=cellfun(@(x) (['C ' x(:,:) '\n']),out,'UniformOutput',false);
            out=join(out,"");
            out=char(out);
        end
        
        function out = getFaultType(app)
            
            ftype=split(app.FaulttypeFTYPEButtonGroup.SelectedObject.Text,' - ');
            
            out=str2double(ftype(1));
            
        end
        
        function [out] = getGroundImpedance(app,sec)
            out=cell2mat(app.UITableGroundImp.Data(sec,:));
        end
        
        function [out] = getSEImpedance(app,term)
            if term==1
                out(1,1)=app.RGG1EditFieldT1.Value; %r ground
                out(1,2)=app.LGG1EditFieldT1.Value; %l
                out(1,3)=app.CGG1EditFieldT1.Value; %c
                
                out(2,1)=app.RNC1EditFieldT1.Value; %r neutral
                out(2,2)=app.LNC1EditFieldT1.Value; %l
                out(2,3)=app.CNC1EditFieldT1.Value; %c
                
            elseif term==2
                out(1,1)=app.RGG1EditFieldT2.Value; %r ground
                out(1,2)=app.LGG1EditFieldT2.Value; %l
                out(1,3)=app.CGG1EditFieldT2.Value; %c
                
                out(2,1)=app.RNC1EditFieldT2.Value; %r neutral
                out(2,2)=app.LNC1EditFieldT2.Value; %l
                out(2,3)=app.CNC1EditFieldT2.Value; %c
                
            end
        end
        
        function out = getSelectedShWireOutputs(app,fault_sec)
            
            out=[];
            
            if app.OrsectionsbeforeandafterfaultShWireCurrCheckBox.Value
                n=app.SpinnerSWCurr.Value;
                numsec=app.NumberofsectionsSpinner.Value;
                out=fault_sec-n:fault_sec+n;
                out=out((out>=1 & out<=numsec));
            else
                outputmap=app.UITableSWCurr.Data;
                outputmap(any(cellfun(@isempty,outputmap),2),:) = [];
                if isempty(outputmap)
                    return;
                end
                for i=1:size(outputmap,1)
                    if cellfun(@isnumeric,(outputmap(i,1)));outputmap(i,1)={cellfun(@num2str, (outputmap(i,1)))};end;
                    if cellfun(@isnumeric,(outputmap(i,2)));outputmap(i,2)={cellfun(@num2str, (outputmap(i,2)))};end;
                end
                outputmap=str2double(outputmap);
                for j=1:size(outputmap,1)
                    out=[out  outputmap(j,1):outputmap(j,2)];
                end
                out=unique(out);
            end
            
        end
        
        function out = getSelectedTow2GroundOutputs(app,fault_sec)
            
            out=[];
            
            if app.OrsectionsbeforeandafterfaultTowShuntParamCheckBox.Value
                n=app.SpinnerTow2Ground.Value;
                numsec=app.NumberofsectionsSpinner.Value;
                out=fault_sec-n:fault_sec+n;
                out=out((out>=1 & out<=numsec));
            else
                outputmap=app.UITableTow2Ground.Data;
                outputmap(any(cellfun(@isempty,outputmap),2),:) = [];
                if isempty(outputmap)
                    return;
                end
                for i=1:size(outputmap,1)
                    if cellfun(@isnumeric,(outputmap(i,1)));outputmap(i,1)={cellfun(@num2str, (outputmap(i,1)))};end;
                    if cellfun(@isnumeric,(outputmap(i,2)));outputmap(i,2)={cellfun(@num2str, (outputmap(i,2)))};end;
                end
                outputmap=str2double(outputmap);
                for j=1:size(outputmap,1)
                    out=[out  outputmap(j,1):outputmap(j,2)];
                end
                out=unique(out);
            end
            
        end
        
        function [term1, term2] = getSourceParams(app)
            ncirc=valIfTrue(app,app.DoublecircuitButton.Value==1,2,1);
            
            term1(1,1)=2*app.RMSLLamplitudeV1EditFieldT1.Value/(sqrt(2)*sqrt(3)); %amplitude
            term1(1,2)=app.FrequencyHz1EditFieldT1.Value; %freq
            term1(1,3)=app.Phaseangledeg1EditFieldT1.Value; %angle
            term1(1,4)=app.Tstarts1EditFieldT1.Value; %tstart
            term1(1,5)=app.Tstops1EditFieldT1.Value; %tstop
            
            term2(1,1)=2*app.RMSLLamplitudeV1EditFieldT2.Value/(sqrt(2)*sqrt(3)); %amplitude
            term2(1,2)=app.FrequencyHz1EditFieldT2.Value; %freq
            term2(1,3)=app.Phaseangledeg1EditFieldT2.Value; %angle
            term2(1,4)=app.Tstarts1EditFieldT2.Value; %tstart
            term2(1,5)=app.Tstops1EditFieldT2.Value; %tstop
            
            if ncirc==2
                term1(2,1)=2*app.RMSLLamplitudeV2EditFieldT1.Value/(sqrt(2)*sqrt(3)); %amplitude
                term1(2,2)=app.FrequencyHz2EditFieldT1.Value; %freq
                term1(2,3)=app.Phaseangledeg2EditFieldT1.Value; %angle
                term1(2,4)=app.Tstarts2EditFieldT1.Value; %tstart
                term1(2,5)=app.Tstops2EditFieldT1.Value; %tstop
                
                term2(2,1)=2*app.RMSLLamplitudeV2EditFieldT2.Value/(sqrt(2)*sqrt(3)); %amplitude
                term2(2,2)=app.FrequencyHz2EditFieldT2.Value; %freq
                term2(2,3)=app.Phaseangledeg2EditFieldT2.Value; %angle
                term2(2,4)=app.Tstarts2EditFieldT2.Value; %tstart
                term2(2,5)=app.Tstops2EditFieldT2.Value; %tstop
            end
            
        end
        
        function [term1, term2] = getTheveninParams(app)
            ncirc=valIfTrue(app,app.DoublecircuitButton.Value==1,2,1);
            
            term1(1,1)=app.R0S1EditFieldT1.Value; %R0
            term1(1,2)=app.L0S1EditFieldT1.Value; %L0
            term1(1,3)=app.R1S1EditFieldT1.Value; %R+
            term1(1,4)=app.L1S1EditFieldT1.Value; %L+
            
            term2(1,1)=app.R0S1EditFieldT2.Value; %R0
            term2(1,2)=app.L0S1EditFieldT2.Value; %L0
            term2(1,3)=app.R1S1EditFieldT2.Value; %R+
            term2(1,4)=app.L1S1EditFieldT2.Value; %L+
            
            if ncirc==2
                term1(2,1)=app.R0S2EditFieldT1.Value; %R0
                term1(2,2)=app.L0S2EditFieldT1.Value; %L0
                term1(2,3)=app.R1S2EditFieldT1.Value; %R+
                term1(2,4)=app.L1S2EditFieldT1.Value; %L+
                
                term2(2,1)=app.R0S2EditFieldT2.Value; %R0
                term2(2,2)=app.L0S2EditFieldT2.Value; %L0
                term2(2,3)=app.R1S2EditFieldT2.Value; %R+
                term2(2,4)=app.L1S2EditFieldT2.Value; %L+
                
            end
            
        end
        
        function [out] = makeMainATP(app, fault_sec)
            
            % header
            out='BEGIN NEW DATA CASE\n';
            out=strcat(out, sprintf('C Project ID: %s',getProjectID(app)));
            out=strcat(out, '\nC Project description:\n');
            out=strcat(out, getProjectDesc(app));
            out=strcat(out, sprintf('C Case description: fault simulation at section %d.',fault_sec));
            out=strcat(out, '\nC RTFS developed by A. Martins-Britto, Sept. 2020.\n');
            out=strcat(out, 'C Reference: Realistic Modeling of Power Lines for Transient Electromagnetic Interference Studies \nC DOI: 10.13140/RG.2.2.29709.56800\nC ******\n');
            out=strcat(out, '$DUMMY, XYZ000\n');
            out=strcat(out, 'C  dT  >< Tmax >< Xopt >< Copt ><Epsiln>\n');
            out=strcat(out, writeATPSettings(app));
            
            %models
            out=strcat(out, writeFaultModels(app));
            
            %branch - now the fun begins
            out=strcat(out, '/BRANCH\n');
            out=strcat(out, 'C < n 1>< n 2><ref1><ref2><       R      ><      L       ><      C       ><   >\n');
            out=strcat(out, 'C < n 1>< n 2><ref1><ref2><     R    ><    A     ><    B     ><  Lenght  ><><>0\n');
            % short circuit thevenin equivalents
            [T1, T2]=getTheveninParams(app);
            out=strcat(out, writeThEqCard(app,T1,1));
            out=strcat(out, writeThEqCard(app,T2,2));
            out=strcat(out, '$VINTAGE,1\n');
            nsec=app.NumberofsectionsSpinner.Value;
            % tower ground impedances
            for i=1:nsec
                out=strcat(out, writeTowGroundCard(app,getGroundImpedance(app,i),i,fault_sec));
            end
            % substation ground impedances + neutral connections
            out=strcat(out, writeSEGroundCard(app,getSEImpedance(app,1),1));
            out=strcat(out, writeSEGroundCard(app,getSEImpedance(app,2),2));
            % fault components
            out=strcat(out, writeFaultBranchComponents(app));
            out=strcat(out, '$VINTAGE,0\n');
            % include LCC pch files
            for i=1:nsec
                out=strcat(out, writeLCCPunchFile(app,i));
            end
            listOfShWires=getSelectedTow2GroundOutputs(app,fault_sec);
            %switch - current measurements
            out=strcat(out, '/SWITCH\n');
            out=strcat(out, 'C < n 1>< n 2>< Tclose ><Top/Tde ><   Ie   ><Vf/CLOP ><  type  >\n');
            out=strcat(out, writePhaseCurrProbesCard(app,1));
            out=strcat(out, writePhaseCurrProbesCard(app,2));
            
            if outputShWireCurr(app)
                for i=1:nsec
                    if ismember(i,listOfShWires)
                        measure_status=1;
                    else
                        measure_status=0;
                    end
                    out=strcat(out, writeShWireCurrProbesCard(app,i, measure_status, fault_sec));
                end
            end
            out=strcat(out, writeFaultBranchConnections(app,fault_sec));
            
            %source
            out=strcat(out, '/SOURCE\n');
            out=strcat(out, 'C < n 1><>< Ampl.  >< Freq.  ><Phase/T0><   A1   ><   T1   >< TSTART >< TSTOP  >\n');
            [T1,T2]=getSourceParams(app);
            out=strcat(out, writeVoltageSourceCard(app,T1,1));
            out=strcat(out, writeVoltageSourceCard(app,T2,2));
            
            %output - node voltages
            out=strcat(out, '/OUTPUT\n');
            if outputPhaseVolt(app,1)
                out=strcat(out, writePhaseVoltProbesCard(app,1));
            end
            if outputPhaseVolt(app,2)
                out=strcat(out, writePhaseVoltProbesCard(app,2));
            end
            if outputFaultBranchVolt(app)
                out=strcat(out, writeFaultBranchVoltProbesCard(app));
            end
            %footer
            out=strcat(out, 'BLANK MODELS\n');
            out=strcat(out, 'BLANK BRANCH\n');
            out=strcat(out, 'BLANK SWITCH\n');
            out=strcat(out, 'BLANK SOURCE\n');
            out=strcat(out, 'BLANK OUTPUT\n');
            out=strcat(out, 'BLANK PLOT\n');
            out=strcat(out, 'BEGIN NEW DATA CASE\n');
            out=strcat(out, 'BLANK');
            
        end
        
        function out = outputFaultBranchCurr(app)
            
            out=app.FaultbranchphasecurrentsCheckBox.Value;
            
        end
        
        function out = outputFaultBranchVolt(app)
            
            out=app.FaultbranchphasevoltagesCheckBox.Value;
            
        end
        
        function out = outputPhaseCurr(app,this_term)
            
            out=false;
            if this_term==1
                out=app.Terminal1phasecurrentsCheckBox.Value;
            elseif this_term==2
                out=app.Terminal2phasecurrentsCheckBox.Value;
            end
            
        end
        
        function out = outputPhaseVolt(app,this_term)
            
            out=false;
            if this_term==1
                out=app.Terminal1phasevoltagesCheckBox.Value;
            elseif this_term==2
                out=app.Terminal2phasevoltagesCheckBox.Value;
            end
            
        end
        
        function out = outputShWireCurr(app)
            
            out=app.ShieldwirereturncurrentsCheckBox.Value && ~app.NoneSWButton.Value;
            
        end
        
        function out = outputTow2GndCurr(app)
            
            out=app.TowertogroundcurrentsCheckBox.Value;
            
        end
        
        function out = outputTow2GndVolt(app)
            
            out=app.TowertogroundvoltagesCheckBox.Value;
            
        end
        
        function [out] = writeATPSettings(app)
            
            dT=app.deltaTsEditField.Value;
            Tmax=app.TmaxsEditField.Value;
            
            out1=blanks(80);
            out2='     500       1       1       1       1       0       0       1       0\n';
            out1(1:8)=modprin(app,8,dT);
            out1(9:16)=modprin(app,8,Tmax);
            
            out=strcat(out1,'\n',out2);
            
        end
        
        function out = writeFaultBranchComponents(app)
            
            out1=blanks(80);
            out2=blanks(80);
            out3=blanks(80);
            out4=blanks(80);
            out5=blanks(80);
            out6=blanks(80);
            
            RG=app.PhasetogroundresistanceEditField.Value;
            RF=app.PhasetophaseresistanceEditField.Value;
            
            out1(3:6)='RG_C';
            out1(9:14)='FGOUTC';
            out1(27:42)=modprin(app,16,RG);
            out1(80)='0';
            
            out2(3:6)='RG_A';
            out2(9:14)='FGOUTA';
            out2(27:42)=modprin(app,16,RG);
            out2(80)='0';
            
            out3(3:5)='RFB';
            out3(9:11)='RFA';
            out3(27:42)=modprin(app,16,RF);
            out3(80)='0';
            
            out4(3:5)='RFC';
            out4(9:11)='RFB';
            out4(27:42)=modprin(app,16,RF);
            out4(80)='0';
            
            out5(3:5)='RFC';
            out5(9:11)='RFA';
            out5(27:42)=modprin(app,16,RF);
            out5(80)='0';
            
            out6(3:6)='RG_B';
            out6(9:14)='FGOUTB';
            out6(27:42)=modprin(app,16,RG);
            out6(80)='0';
            
            
            out=strcat(out1,'\n',out2,'\n',out3,'\n',out4,'\n',out5,'\n',out6,'\n');
            
        end
        
        function out = writeFaultBranchConnections(app,fault_sec)
            
            measure_status=valIfTrue(app,app.FaultbranchphasecurrentsCheckBox.Value,1,0);
            circ_fault=valIfTrue(app, strcmp(app.FaultedcircuitButtonGroup.SelectedObject.Text,'Circuit 1'),1,2);
            seq='ABC';
            
            out1='13F_INC RFC                                                           CHC      0\n';
            out1=strcat(out1, '13F_INB RFB                                                           CHB      0\n');
            out1=strcat(out1, '13F_INA RFA                                                           CHA      0\n');
            out1=strcat(out1, '13RFC   RG_C                                                          CHT      0\n');
            out1=strcat(out1, '13RFB   RG_B                                                          CHT      0\n');
            out1=strcat(out1, '13RFA   RG_A                                                          CHT      0\n');
            
            out2=blanks(80);
            out2(3:8)=sprintf('%d%04d%s',circ_fault,fault_sec,seq(1));
            out2(9:13)='F_INA';
            out2(55:63)='MEASURING';
            out2(80)=sprintf('%d',measure_status);
            
            out3=blanks(80);
            out3(3:8)=sprintf('%d%04d%s',circ_fault,fault_sec,seq(2));
            out3(9:13)='F_INB';
            out3(55:63)='MEASURING';
            out3(80)=sprintf('%d',measure_status);
            
            out4=blanks(80);
            out4(3:8)=sprintf('%d%04d%s',circ_fault,fault_sec,seq(3));
            out4(9:13)='F_INC';
            out4(55:63)='MEASURING';
            out4(80)=sprintf('%d',measure_status);
            
            out5=blanks(80);
            out5(3:8)='FGOUTA';
            out5(9:13)=sprintf('0%04d',fault_sec);
            out5(55:63)='MEASURING';
            out5(80)=sprintf('%d',measure_status);
            
            out6=blanks(80);
            out6(3:8)='FGOUTB';
            out6(9:13)=sprintf('0%04d',fault_sec);
            out6(55:63)='MEASURING';
            out6(80)=sprintf('%d',measure_status);
            
            out7=blanks(80);
            out7(3:8)='FGOUTC';
            out7(9:13)=sprintf('0%04d',fault_sec);
            out7(55:63)='MEASURING';
            out7(80)=sprintf('%d',measure_status);
            
            out=strcat(out1,out2,'\n',out3,'\n',out4,'\n',out5,'\n',out6,'\n',out7,'\n');
            
        end
        
        function out = writeFaultBranchVoltProbesCard(app)
            
            out=[];
            seq='ABC';
            
            out=blanks(80);
            out(3:7)=sprintf('F_IN%s',seq(1));
            out(9:13)=sprintf('F_IN%s',seq(2));
            out(15:19)=sprintf('F_IN%s',seq(3));
            out=strcat(out,'\n');
            
        end
        
        function [out] = writeFaultModels(app)
            
            anginc=app.FaultincidenceangleSpinner.Value;
            ftype=getFaultType(app);
            freq=app.FreqHzEditField.Value;
            npre=app.CyclesbeforefaultSpinner.Value;
            
            out=[];
            
            out=strcat(out,'/MODELS\n');
            out=strcat(out,'MODELS\n');
            out=strcat(out,'INPUT\n');
            out=strcat(out,'  MM0001 {v(F_INA)}\n');
            out=strcat(out,'  MM0002 {v(F_INB)}\n');
            out=strcat(out,'  MM0003 {v(F_INC)}\n');
            out=strcat(out,'OUTPUT\n');
            out=strcat(out,'  CHA\n');
            out=strcat(out,'  CHB\n');
            out=strcat(out,'  CHC\n');
            out=strcat(out,'  CHT\n');
            out=strcat(out,'MODEL modfalta\n');
            out=strcat(out,'INPUT\n');
            out=strcat(out,'    VFa\n');
            out=strcat(out,'    VFb\n');
            out=strcat(out,'    VFc\n');
            out=strcat(out,'DATA\n');
            out=strcat(out,'    anginc\n');
            out=strcat(out,'    tipo\n');
            out=strcat(out,'    f\n');
            out=strcat(out,'    npre\n');
            out=strcat(out,'OUTPUT\n');
            out=strcat(out,'    swFa\n');
            out=strcat(out,'    swFb\n');
            out=strcat(out,'    swFc\n');
            out=strcat(out,'    swT\n');
            out=strcat(out,'VAR\n');
            out=strcat(out,'    dVa\n');
            out=strcat(out,'    dVb\n');
            out=strcat(out,'    dVc\n');
            out=strcat(out,'    dVab\n');
            out=strcat(out,'    dVbc\n');
            out=strcat(out,'    dVca\n');
            out=strcat(out,'    bVa[1..2]\n');
            out=strcat(out,'    bVb[1..2]\n');
            out=strcat(out,'    bVc[1..2]\n');
            out=strcat(out,'    bVab[1..2]\n');
            out=strcat(out,'    bVbc[1..2]\n');
            out=strcat(out,'    bVca[1..2]\n');
            out=strcat(out,'    bt[1..2]\n');
            out=strcat(out,'    swFa\n');
            out=strcat(out,'    swFb\n');
            out=strcat(out,'    swFc\n');
            out=strcat(out,'    swT\n');
            out=strcat(out,'    contSWA\n');
            out=strcat(out,'    contSWB\n');
            out=strcat(out,'    contSWC\n');
            out=strcat(out,'    contSWT\n');
            out=strcat(out,'    flagSWA\n');
            out=strcat(out,'    flagSWB\n');
            out=strcat(out,'    flagSWC\n');
            out=strcat(out,'    flagSWT\n');
            out=strcat(out,'    faux\n');
            out=strcat(out,'INIT\n');
            out=strcat(out,'    dVa:=0\n');
            out=strcat(out,'    dVb:=0\n');
            out=strcat(out,'    dVc:=0\n');
            out=strcat(out,'    dVab:=0\n');
            out=strcat(out,'    dVbc:=0\n');
            out=strcat(out,'    dVca:=0\n');
            out=strcat(out,'    bVa[1..2]:=0\n');
            out=strcat(out,'    bVb[1..2]:=0\n');
            out=strcat(out,'    bVc[1..2]:=0\n');
            out=strcat(out,'    bVab[1..2]:=0\n');
            out=strcat(out,'    bVbc[1..2]:=0\n');
            out=strcat(out,'    bVca[1..2]:=0\n');
            out=strcat(out,'    swFa:=0.0\n');
            out=strcat(out,'    swFb:=0.0\n');
            out=strcat(out,'    swFc:=0.0\n');
            out=strcat(out,'    swT:=0.0\n');
            out=strcat(out,'    contSWA:=0.0\n');
            out=strcat(out,'    contSWB:=0.0\n');
            out=strcat(out,'    contSWC:=0.0\n');
            out=strcat(out,'    contSWT:=0.0\n');
            out=strcat(out,'    flagSWA:=0.0\n');
            out=strcat(out,'    flagSWB:=0.0\n');
            out=strcat(out,'    flagSWC:=0.0\n');
            out=strcat(out,'    flagSWT:=0.0\n');
            out=strcat(out,'    faux:=0.0\n');
            out=strcat(out,'ENDINIT\n');
            out=strcat(out,'EXEC\n');
            out=strcat(out,'    bVa[1] := bVa[2]\n');
            out=strcat(out,'    bVb[1] := bVb[2]\n');
            out=strcat(out,'    bVc[1] := bVc[2]\n');
            out=strcat(out,'    bVab[1] := bVab[2]\n');
            out=strcat(out,'    bVbc[1] := bVbc[2]\n');
            out=strcat(out,'    bVca[1] := bVca[2]\n');
            out=strcat(out,'\n');
            out=strcat(out,'    bVa[2] := VFa\n');
            out=strcat(out,'    bVb[2] := VFb\n');
            out=strcat(out,'    bVc[2] := VFc\n');
            out=strcat(out,'    bVab[2] := VFa-VFb\n');
            out=strcat(out,'    bVbc[2] := VFb-VFc\n');
            out=strcat(out,'    bVca[2] := VFc-VFa\n');
            out=strcat(out,'\n');
            out=strcat(out,'    dVa:=(bVa[2] - bVa[1])/timestep\n');
            out=strcat(out,'    dVb:=(bVb[2] - bVb[1])/timestep\n');
            out=strcat(out,'    dVc:=(bVc[2] - bVc[1])/timestep\n');
            out=strcat(out,'    dVab:=dVa-dVb\n');
            out=strcat(out,'    dVbc:=dVb-dVc\n');
            out=strcat(out,'    dVca:=dVc-dVa\n');
            out=strcat(out,'\n');
            out=strcat(out,'IF (tipo=1) AND (dVa>0) AND (bVa[2]*bVa[1]<0) AND (t>=npre/f) AND (faux=0) THEN\n');
            out=strcat(out,'        flagSWA:=1.0\n');
            out=strcat(out,'        flagSWT:=1.0\n');
            out=strcat(out,'        faux:=1.0\n');
            out=strcat(out,'ENDIF\n');
            out=strcat(out,'IF (tipo=2) AND (dVb>0) AND (bVb[2]*bVb[1]<0) AND (t>=npre/f) AND (faux=0) THEN\n');
            out=strcat(out,'        flagSWB:=1.0\n');
            out=strcat(out,'        flagSWT:=1.0\n');
            out=strcat(out,'        faux:=1.0\n');
            out=strcat(out,'ENDIF\n');
            out=strcat(out,'IF (tipo=3) AND (dVc>0) AND (bVc[2]*bVc[1]<0) AND (t>=npre/f) AND (faux=0) THEN\n');
            out=strcat(out,'        flagSWC:=1.0\n');
            out=strcat(out,'        flagSWT:=1.0\n');
            out=strcat(out,'        faux:=1.0\n');
            out=strcat(out,'ENDIF\n');
            out=strcat(out,'IF (tipo=4) AND (dVab>0) AND (bVab[2]*bVab[1]<0) AND (t>=npre/f) AND (faux=0) \n');
            out=strcat(out,'THEN\n');
            out=strcat(out,'        flagSWA:=1.0\n');
            out=strcat(out,'        flagSWB:=1.0\n');
            out=strcat(out,' faux:=1.0\n');
            out=strcat(out,'ENDIF\n');
            out=strcat(out,'IF (tipo=5) AND (dVbc>0) AND (bVbc[2]*bVbc[1]<0) AND (t>=npre/f) AND (faux=0) \n');
            out=strcat(out,'THEN\n');
            out=strcat(out,'        flagSWB:=1.0\n');
            out=strcat(out,'        flagSWC:=1.0\n');
            out=strcat(out,' faux:=1.0\n');
            out=strcat(out,'ENDIF\n');
            out=strcat(out,'IF (tipo=6) AND (dVca>0) AND (bVca[2]*bVca[1]<0) AND (t>=npre/f) AND (faux=0) \n');
            out=strcat(out,'THEN\n');
            out=strcat(out,'        flagSWC:=1.0\n');
            out=strcat(out,'        flagSWA:=1.0\n');
            out=strcat(out,' faux:=1.0\n');
            out=strcat(out,'ENDIF\n');
            out=strcat(out,'IF (tipo=7) AND (dVab>0) AND (bVab[2]*bVab[1]<0) AND (t>=npre/f) AND (faux=0) \n');
            out=strcat(out,'THEN\n');
            out=strcat(out,'        flagSWA:=1.0\n');
            out=strcat(out,'        flagSWB:=1.0\n');
            out=strcat(out,'        flagSWT:=1.0\n');
            out=strcat(out,' faux:=1.0\n');
            out=strcat(out,'ENDIF\n');
            out=strcat(out,'IF (tipo=8) AND (dVbc>0) AND (bVbc[2]*bVbc[1]<0) AND (t>=npre/f) AND (faux=0) \n');
            out=strcat(out,'THEN\n');
            out=strcat(out,'        flagSWB:=1.0\n');
            out=strcat(out,'        flagSWC:=1.0\n');
            out=strcat(out,'        flagSWT:=1.0\n');
            out=strcat(out,' faux:=1.0\n');
            out=strcat(out,'ENDIF\n');
            out=strcat(out,'IF (tipo=9) AND (dVca>0) AND (bVca[2]*bVca[1]<0) AND (t>=npre/f) AND (faux=0) \n');
            out=strcat(out,'THEN\n');
            out=strcat(out,'        flagSWC:=1.0\n');
            out=strcat(out,'        flagSWA:=1.0\n');
            out=strcat(out,'        flagSWT:=1.0\n');
            out=strcat(out,' faux:=1.0\n');
            out=strcat(out,'ENDIF\n');
            out=strcat(out,'IF (tipo=10) AND (dVa>0) AND (bVa[2]*bVa[1]<0) AND (t>=npre/f) AND (faux=0) THEN\n');
            out=strcat(out,'        flagSWA:=1.0\n');
            out=strcat(out,'        flagSWB:=1.0\n');
            out=strcat(out,'        flagSWC:=1.0\n');
            out=strcat(out,'        faux:=1.0\n');
            out=strcat(out,'ENDIF\n');
            out=strcat(out,'IF (tipo=11) AND (dVa>0) AND (bVa[2]*bVa[1]<0) AND (t>=npre/f) AND (faux=0) THEN\n');
            out=strcat(out,'        flagSWA:=1.0\n');
            out=strcat(out,'        flagSWB:=1.0\n');
            out=strcat(out,'        flagSWC:=1.0\n');
            out=strcat(out,'        flagSWT:=1.0\n');
            out=strcat(out,'        faux:=1.0\n');
            out=strcat(out,'ENDIF    \n');
            out=strcat(out,'\n');
            out=strcat(out,'    IF (flagSWA=1) THEN\n');
            out=strcat(out,'        contSWA:=contSWA+1.0\n');
            out=strcat(out,'    ENDIF\n');
            out=strcat(out,'    IF (flagSWB=1) THEN\n');
            out=strcat(out,'        contSWB:=contSWB+1.0\n');
            out=strcat(out,'    ENDIF\n');
            out=strcat(out,'    IF (flagSWC=1) THEN\n');
            out=strcat(out,'        contSWC:=contSWC+1.0\n');
            out=strcat(out,'    ENDIF\n');
            out=strcat(out,'    IF (flagSWT=1) THEN\n');
            out=strcat(out,'        contSWT:=contSWT+1.0\n');
            out=strcat(out,'    ENDIF\n');
            out=strcat(out,'    \n');
            out=strcat(out,'    IF contSWA*timestep>=(anginc/(360*f)) THEN \n');
            out=strcat(out,'        swFa:=1\n');
            out=strcat(out,'    ENDIF    \n');
            out=strcat(out,'    IF contSWB*timestep>=(anginc/(360*f)) THEN \n');
            out=strcat(out,'        swFb:=1\n');
            out=strcat(out,'    ENDIF   \n');
            out=strcat(out,'    IF contSWC*timestep>=(anginc/(360*f)) THEN \n');
            out=strcat(out,'        swFc:=1\n');
            out=strcat(out,'    ENDIF   \n');
            out=strcat(out,'    IF contSWT*timestep>=(anginc/(360*f)) THEN \n');
            out=strcat(out,'        swT:=1\n');
            out=strcat(out,'    ENDIF       \n');
            out=strcat(out,'ENDEXEC\n');
            out=strcat(out,'ENDMODEL\n');
            out=strcat(out,'USE MODFALTA AS modFalta\n');
            out=strcat(out,'INPUT\n');
            out=strcat(out,'  VFa:= MM0001\n');
            out=strcat(out,'  VFb:= MM0002\n');
            out=strcat(out,'  VFc:= MM0003\n');
            out=strcat(out,'DATA\n');
            out=strcat(out,strcat('  anginc:= ',modprin(app,8,anginc),'\n'));
            out=strcat(out,strcat('  tipo:= ',modprin(app,8,ftype),'\n'));
            out=strcat(out,strcat('  f:= ',modprin(app,8,freq),'\n'));
            out=strcat(out,strcat('  npre:= ',modprin(app,8,npre),'\n'));
            out=strcat(out,'OUTPUT\n');
            out=strcat(out,'  CHA:=swFa\n');
            out=strcat(out,'  CHB:=swFb\n');
            out=strcat(out,'  CHC:=swFc\n');
            out=strcat(out,'  CHT:=swT\n');
            out=strcat(out,'ENDUSE\n');
            out=strcat(out,'RECORD\n');
            out=strcat(out,'  modFalta.swFa AS swFa\n');
            out=strcat(out,'  modFalta.swFb AS swFb\n');
            out=strcat(out,'  modFalta.swFc AS swFc\n');
            out=strcat(out,'  modFalta.swT AS swT\n');
            out=strcat(out,'ENDMODELS\n');
            
        end
        
        function out = writeLCCPunchFile(app,sec)
            projID=app.ProjectIDEditField.Value;
            wdir=app.WorkingdirectoryEditField.Value;
            tgtdir=fullfile(wdir,projID,'LCC');
            fname=sprintf('lcc_sec%04d.pch',sec);
            out=fileread(fullfile(tgtdir,fname));
            out = regexprep(out, {'\r', '\n\n+'}, {'', '\n'});
            out=strcat(out,'\n');
        end
        
        function out = writePhaseCurrProbesCard(app,thisterm)
            
            out=[];
            seq='ABC';
            ncirc=valIfTrue(app,app.DoublecircuitButton.Value==1,2,1);
            
            if thisterm==1
                termIdx=0;
            elseif thisterm==2
                termIdx=app.NumberofsectionsSpinner.Value;
            end
            
            for i=1:ncirc
                tmp_out=blanks(80);
                for j=1:3
                    tmp_out(3:8)=sprintf('TH%dT%d%s',i,thisterm,seq(j));
                    tmp_out(9:14)=sprintf('%d%04d%s',i,termIdx,seq(j));
                    tmp_out(55:63)='MEASURING';
                    tmp_out(80)='1';
                    out=strcat(out,tmp_out,'\n');
                end
            end
            
        end
        
        function out = writePhaseVoltProbesCard(app,thisterm)
            
            out=[];
            seq='ABC';
            ncirc=valIfTrue(app,app.DoublecircuitButton.Value==1,2,1);
            
            for i=1:ncirc
                tmp_out=blanks(80);
                tmp_out(3:8)=sprintf('TH%dT%d%s',i,thisterm,seq(1));
                tmp_out(9:14)=sprintf('TH%dT%d%s',i,thisterm,seq(2));
                tmp_out(15:20)=sprintf('TH%dT%d%s',i,thisterm,seq(3));
                out=strcat(out,tmp_out,'\n');
            end
            
        end
        
        function out = writeSEGroundCard(app,RLC,term)
            
            out1=blanks(80);
            out2=blanks(80);
            
            out1(3:8)=sprintf('GNDSE%d',term);
            out1(27:42)=modprin(app,16,RLC(1,1));
            out1(43:58)=modprin(app,16,RLC(1,2));
            out1(59:74)=modprin(app,16,RLC(1,3));
            out1(80)='3';
            
            if term==1
                termIdx=0;
            elseif term==2
                termIdx=app.NumberofsectionsSpinner.Value;
            end
            
            out2(3:8)=sprintf('GNDSE%d',term);
            out2(9:13)=sprintf('0%04d',termIdx);
            out2(27:42)=modprin(app,16,RLC(2,1));
            out2(43:58)=modprin(app,16,RLC(2,2));
            out2(59:74)=modprin(app,16,RLC(2,3));
            out2(80)='0';
            
            out=strcat(out1,'\n',out2,'\n');
            
            
        end
        
        function out = writeShWireCurrProbesCard(app,sec, status, fault_sec)
            
            if sec <= fault_sec
                in2=3;
                en2=8;
                in1=9;
                en1=14;
            else
                in1=3;
                en1=8;
                in2=9;
                en2=14;
            end
            
            out=blanks(80);
            out(in1:en1)=sprintf('0%04dM',sec);
            out(in2:en2)=sprintf('0%04d ',sec);
            out(55:63)='MEASURING';
            out(80)=sprintf('%d',status);
            out=strcat(out,'\n');
            
            
        end
        
        function out = writeThEqCard(app,src_data,thisterm)
            
            out=[];
            seq='ABC';
            num_src=size(src_data,1);
            cod=[51 52 53];
            
            for i=1:num_src
                tmp_out=blanks(80);
                r0=src_data(i,1);
                l0=src_data(i,2);
                r1=src_data(i,3);
                l1=src_data(i,4);
                RR=[r0 r1 0];
                LL=[l0 l1 0];
                for j=1:3
                    tmp_out(1:2)=sprintf('%2d',cod(j));
                    tmp_out(3:8)=sprintf('VS%dT%d%s',i,thisterm,seq(j));
                    tmp_out(9:14)=sprintf('TH%dT%d%s',i,thisterm,seq(j));
                    tmp_out(27:32)=modprin(app,6,RR(j));
                    tmp_out(33:44)=modprin(app,12,LL(j));
                    out=strcat(out,tmp_out,'\n');
                end
            end
            
        end
        
        function out = writeTow2GndCurrProbesCard(app,sec)
            
            out=blanks(80);
            out(3:8)=sprintf('0%05d',sec);
            out(55:63)='MEASURING';
            out(80)='1';
            out=strcat(out,'\n');
            
            
        end
        
        function out = writeTow2GndVoltProbesCard(app,sec)
            
            out=blanks(80);
            out(3:8)=sprintf('0%05d',sec);
            out=strcat(out,'\n');
            
        end
        
        function out = writeTowGroundCard(app,RLC,sec,fault_sec)
            
            listOfTowers=getSelectedTow2GroundOutputs(app,fault_sec);
            
            flag=0;
            if ismember(sec,listOfTowers)
                if outputTow2GndCurr(app)
                    flag=1;
                end
                if outputTow2GndVolt(app)
                    flag=flag+2;
                end
            end
            
            out=blanks(80);
            out(3:7)=sprintf('0%04d',sec);
            out(27:42)=modprin(app,16,RLC(1));
            out(43:58)=modprin(app,16,RLC(2));
            out(59:74)=modprin(app,16,RLC(3));
            out(80)=sprintf('%d',flag);
            out=strcat(out,'\n');
            
        end
        
        function out = writeVoltageSourceCard(app,src_data,thisterm)
            
            out=[];
            seq='ABC';
            num_src=size(src_data,1);
            
            for i=1:num_src
                tmp_out=blanks(80);
                amp=src_data(i,1);
                freq=src_data(i,2);
                ang=src_data(i,3)+[0 -120 -240];
                tstart=src_data(i,4);
                tstop=src_data(i,5);
                
                for j=1:3
                    tmp_out(1:2)=sprintf('%2d',14);
                    tmp_out(3:8)=sprintf('VS%dT%d%s',i,thisterm,seq(j));
                    tmp_out(11:20)=modprin(app,10,amp);
                    tmp_out(21:30)=modprin(app,10,freq);
                    tmp_out(31:40)=modprin(app,10,ang(j));
                    tmp_out(61:70)=modprin(app,10,tstart);
                    tmp_out(71:80)=modprin(app,10,tstop);
                    out=strcat(out,tmp_out,'\n');
                end
            end
            
        end
        
        function out = getFaultedSections(app)
            
            out=[];
            outputmap=app.UITableFaultSec.Data;
            outputmap(any(cellfun(@isempty,outputmap),2),:) = [];
            if isempty(outputmap)
                return;
            end
            try
                outputmap=cell2mat(outputmap);
            catch
                for i=1:size(outputmap,1)
                    if cellfun(@isnumeric,(outputmap(i,1)));outputmap(i,1)={cellfun(@num2str, (outputmap(i,1)))};end;
                    if cellfun(@isnumeric,(outputmap(i,2)));outputmap(i,2)={cellfun(@num2str, (outputmap(i,2)))};end;
                end
                outputmap=str2double(outputmap);
            end
            for j=1:size(outputmap,1)
                out=[out  outputmap(j,1):outputmap(j,2)];
            end
            out=unique(out);
            
        end
        
        %end public methods
        
    end
    
    methods (Access = private)
        

        function results = disableCircuit2Fields(app)
            app.RMSLLamplitudeV2EditFieldT1.Enable='off';
            app.FrequencyHz2EditFieldT1.Enable='off';
            app.Phaseangledeg2EditFieldT1.Enable='off';
            app.Tstarts2EditFieldT1.Enable='off';
            app.Tstops2EditFieldT1.Enable='off';
            %             app.RGG2EditFieldT1.Enable='off';
            %             app.LGG2EditFieldT1.Enable='off';
            %             app.CGG2EditFieldT1.Enable='off';
            %             app.RNC2EditFieldT1.Enable='off';
            %             app.LNC2EditFieldT1.Enable='off';
            %             app.CNC2EditFieldT1.Enable='off';
            app.R0S2EditFieldT1.Enable='off';
            app.L0S2EditFieldT1.Enable='off';
            app.R1S2EditFieldT1.Enable='off';
            app.L1S2EditFieldT1.Enable='off';
            
            app.RMSLLamplitudeV2EditFieldT2.Enable='off';
            app.FrequencyHz2EditFieldT2.Enable='off';
            app.Phaseangledeg2EditFieldT2.Enable='off';
            app.Tstarts2EditFieldT2.Enable='off';
            app.Tstops2EditFieldT2.Enable='off';
            %             app.RGG2EditFieldT2.Enable='off';
            %             app.LGG2EditFieldT2.Enable='off';
            %             app.CGG2EditFieldT2.Enable='off';
            %             app.RNC2EditFieldT2.Enable='off';
            %             app.LNC2EditFieldT2.Enable='off';
            %             app.CNC2EditFieldT2.Enable='off';
            app.R0S2EditFieldT2.Enable='off';
            app.L0S2EditFieldT2.Enable='off';
            app.R1S2EditFieldT2.Enable='off';
            app.L1S2EditFieldT2.Enable='off';
            
            app.Circuit2Button.Enable='off';
            app.Circuit2Button.Value=0;
            app.Circuit1Button.Value=1;
        end
        
        function results = enableCircuit2Fields(app)
            app.RMSLLamplitudeV2EditFieldT1.Enable='on';
            app.FrequencyHz2EditFieldT1.Enable='on';
            app.Phaseangledeg2EditFieldT1.Enable='on';
            app.Tstarts2EditFieldT1.Enable='on';
            app.Tstops2EditFieldT1.Enable='on';
            %             app.RGG2EditFieldT1.Enable='on';
            %             app.LGG2EditFieldT1.Enable='on';
            %             app.CGG2EditFieldT1.Enable='on';
            %             app.RNC2EditFieldT1.Enable='on';
            %             app.LNC2EditFieldT1.Enable='on';
            %             app.CNC2EditFieldT1.Enable='on';
            app.R0S2EditFieldT1.Enable='on';
            app.L0S2EditFieldT1.Enable='on';
            app.R1S2EditFieldT1.Enable='on';
            app.L1S2EditFieldT1.Enable='on';
            
            app.RMSLLamplitudeV2EditFieldT2.Enable='on';
            app.FrequencyHz2EditFieldT2.Enable='on';
            app.Phaseangledeg2EditFieldT2.Enable='on';
            app.Tstarts2EditFieldT2.Enable='on';
            app.Tstops2EditFieldT2.Enable='on';
            %             app.RGG2EditFieldT2.Enable='on';
            %             app.LGG2EditFieldT2.Enable='on';
            %             app.CGG2EditFieldT2.Enable='on';
            %             app.RNC2EditFieldT2.Enable='on';
            %             app.LNC2EditFieldT2.Enable='on';
            %             app.CNC2EditFieldT2.Enable='on';
            app.R0S2EditFieldT2.Enable='on';
            app.L0S2EditFieldT2.Enable='on';
            app.R1S2EditFieldT2.Enable='on';
            app.L1S2EditFieldT2.Enable='on';
            
            app.Circuit2Button.Enable='on';
        end
        
        function results = setFieldValues(app)
            conf_exists=evalin( 'base', 'exist(''rtfsconf'',''var'') == 1' );
            if conf_exists
            else
                app.UITableSL.Data=cell(1,1);
                app.UITableSL.Data(cellfun(@isempty,app.UITableSL.Data)) = num2cell(app.DefaultspanlengthmEditField.Value);
                app.TotallinelengthmEditField.Value=sum(cell2mat(app.UITableSL.Data));
                app.UITableAttSetSec.ColumnFormat = {{'1'} {'1'} {'1'}};
                app.WorkingdirectoryEditField.Value=pwd;
                app.UITableAttSets.Data=cell(1,1);
                app.UITableAttSets.Data(cellfun(@isempty,app.UITableAttSets.Data)) = cellstr(jsonencode('undefined'));
                app.UITableSoilRes.Data=cell(1,1);
                app.UITableSoilRes.Data(cellfun(@isempty,app.UITableSoilRes.Data)) = num2cell(app.DefaultSoilResEditField.Value);
                app.UITableGroundImp.Data=cell(1,3);
                app.UITableGroundImp.Data(cellfun(@isempty,app.UITableGroundImp.Data)) = num2cell([app.GroundImpREditField.Value app.GroundImpLEditField.Value app.GroundImpCEditField.Value]);
                app.UITableFaultSec.Data=cell(1,2);
                app.UITableFaultSec.Data(1,:)=num2cell([1 1]);
                app.UITableFaultSec.ColumnFormat = {{'1'} {'1'}};
                app.UITableSWCurr.Data=cell(1,2);
                app.UITableSWCurr.Data(1,:)=num2cell([1 1]);
                app.UITableSWCurr.ColumnFormat = {{'1'} {'1'}};
                app.UITableTow2Ground.Data=cell(1,2);
                app.UITableTow2Ground.Data(1,:)=num2cell([1 1]);
                app.UITableTow2Ground.ColumnFormat = {{'1'} {'1'}};
            end
        end
        
        function results = saveAllData(app)
            
            results=false;
            [testdir,msg]=validateWorkDir(app);
            if ~testdir
                f = msgbox(msg, 'You lose, fella.','error');
                return;
            end
            
            [testfile,msg]=validateProjID(app);
            if ~testfile
                f = msgbox(msg, 'You lose, fella.','error');
                return;
            end
            
            %ok now do stuff
            projID=app.ProjectIDEditField.Value;
            wdir=app.WorkingdirectoryEditField.Value;
            projdir=fullfile(wdir,projID);
            orig_state = warning;warning('off','all');
            mkdir(projdir);
            fname=[projID '.mat'];
            rtfsconf = readAllAppValues(app);
            save(fullfile(projdir,fname),'rtfsconf');
            warning(orig_state);
            results=true;
        end
        
        function [isgood, msg] = validateProjID(app)
            projID=app.ProjectIDEditField.Value;
            isgood=true;
            msg='';
            BadChar = '<>:"/\|?*';
            BadName = {'CON', 'PRN', 'AUX', 'NUL', 'CLOCK$', ...
                'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', ...
                'COM7', 'COM8', 'COM9', ...
                'LPT1', 'LPT2', 'LPT3', 'LPT4', 'LPT5', 'LPT6', ...
                'LPT7', 'LPT8', 'LPT9'};
            if isempty(projID)
                isgood=false;
                msg='You must specify a project ID!';
                return;
            else
                bad = ismember(BadChar, projID);
                if any(bad)
                    msg = ['Project ID contains bad characters: ', BadChar(bad)];
                    isgood=false;
                    return;
                elseif any(projID < 32)
                    msg = ['Project ID  contains non printable characters, ASCII:', sprintf(' %d', projID(projID < 32))];
                    isgood=false;
                    return;
                elseif ~isempty(projID) && (projID(end) == ' ' || projID(end) == '.')
                    msg = 'Project ID must not contain a trailing space or dot.';
                    isgood=false;
                    return;
                else
                    [~, name] = fileparts(projID);
                    if any(strcmpi(name, BadName))
                        msg = ['Project ID not allowed: ', name];
                        isgood=false;
                        return;
                    end
                end
            end
        end
        
        function [isgood, msg] = validateWorkDir(app)
            wdir=app.WorkingdirectoryEditField.Value;
            isgood=true;
            msg='';
            if  ~exist(wdir, 'dir')
                msg='Invalid working directory!';
                isgood=false;
                return;
            end
        end
        
        function results = onIfTrue(app,arg)
            if arg==1
                results='on';
            else
                results='off';
            end
        end
        
        function [results, msg] = loadFromFile(app,fname,nrows,ncols)
            results=[];
            msg=[];
            try
                data=load(fname);
            catch
                msg='File contains invalid (non-numerical) data!';
                return;
            end
            
            if size(data,1)~=nrows || size(data,2)~=ncols
                msg=['Invalid array dimensions! File must contain ' num2str(nrows) ' row(s) x ' num2str(ncols) ' column(s).'];
                return;
            end
            
            results=data;
        end
        
        
        function results = resetAttSetTable(app)
            value = app.NumberofattributesetsSpinner.Value;
            if value == 1
                app.DefaultattributesetDropDown.Items={'1'};
                app.UITableAttSetSec.ColumnFormat{3} ={'1'};
            else
                app.DefaultattributesetDropDown.Items=convertStringsToChars(string(1:value));
                app.UITableAttSetSec.ColumnFormat{3} = convertStringsToChars(string(1:value));
            end
            app.UITableAttSets.Data=cell(value,1);
            app.UITableAttSets.Data(cellfun(@isempty,app.UITableAttSets.Data)) = cellstr(jsonencode('undefined'));
        end
        
        function rtfsconf = readAllAppValues(app)
            rtfsconf=struct;
            
            props = properties(app);
            
            for iprop = 1:length(props)
                thisprop = props{iprop};
                
                if strcmp(app.(thisprop).Type,'uieditfield') || strcmp(app.(thisprop).Type,'uitextarea') || ...
                        strcmp(app.(thisprop).Type,'uinumericeditfield') || strcmp(app.(thisprop).Type,'uicheckbox') || ...
                        strcmp(app.(thisprop).Type,'uispinner') || strcmp(app.(thisprop).Type,'uiradiobutton') || ...
                        strcmp(app.(thisprop).Type,'uidropdown') || strcmp(app.(thisprop).Type,'uitable')
                    
                    rtfsconf.(thisprop)=struct;
                    
                    if isprop(app.(thisprop),'Value')
                        rtfsconf.(thisprop).Value=app.(thisprop).Value;
                    end
                    
                    rtfsconf.(thisprop).Enable=app.(thisprop).Enable;
                    rtfsconf.(thisprop).Visible=app.(thisprop).Visible;
                    
                    if isprop(app.(thisprop),'Editable')
                        rtfsconf.(thisprop).Editable=app.(thisprop).Editable;
                    end
                    
                    if isprop(app.(thisprop),'List')
                        rtfsconf.(thisprop).List=app.(thisprop).List;
                    end
                    
                    if isprop(app.(thisprop),'Data')
                        rtfsconf.(thisprop).Data=app.(thisprop).Data;
                    end
                    
                end
                
            end
        end
        
        function results = writeAllAppValues(app,rtfsconf)
            
            props = properties(app);
            
            for iprop = 1:length(props)
                thisprop = props{iprop};
                
                if strcmp(app.(thisprop).Type,'uieditfield') || strcmp(app.(thisprop).Type,'uitextarea') || ...
                        strcmp(app.(thisprop).Type,'uinumericeditfield') || strcmp(app.(thisprop).Type,'uicheckbox') || ...
                        strcmp(app.(thisprop).Type,'uispinner') || strcmp(app.(thisprop).Type,'uiradiobutton') || ...
                        strcmp(app.(thisprop).Type,'uidropdown') || strcmp(app.(thisprop).Type,'uitable')
                    
                    
                    if isprop(app.(thisprop),'Value')
                        app.(thisprop).Value=rtfsconf.(thisprop).Value;
                    end
                    
                    app.(thisprop).Enable=rtfsconf.(thisprop).Enable;
                    app.(thisprop).Visible=rtfsconf.(thisprop).Visible;
                    
                    if isprop(app.(thisprop),'Editable')
                        app.(thisprop).Editable=rtfsconf.(thisprop).Editable;
                    end
                    
                    if isprop(app.(thisprop),'List')
                        app.(thisprop).List=rtfsconf.(thisprop).List;
                    end
                    
                    if isprop(app.(thisprop),'Data')
                        app.(thisprop).Data=rtfsconf.(thisprop).Data;
                    end
                    
                end
                
            end
        end


        function out = makeAllLCCFiles(app)
            out=true;
            atpsolver=app.ATPsolverEditField.Value;
            projID=app.ProjectIDEditField.Value;
            wdir=app.WorkingdirectoryEditField.Value;
            projdir=fullfile(wdir,projID);
            tgtdir=fullfile(wdir,projID,'LCC');
            
            orig_state = warning;warning('off','all');
            mkdir(projdir);
            mkdir(tgtdir);
            addpath(fullfile(pwd,'functions'));
            warning(orig_state);
            
            num_sec=app.NumberofsectionsSpinner.Value;
            app.ConsoleTextArea.Value=[app.ConsoleTextArea.Value;'Computing line parameters...'];
            for k=1:num_sec
                app.ConsoleTextArea.Value=[app.ConsoleTextArea.Value;sprintf('Processing LCC section %d...\n',k)];
                fname=sprintf('lcc_sec%04d.atp',k);
                fid = fopen(fullfile(tgtdir,fname),'wt');
                fprintf(fid, makeLCCC(app,k));
                fclose(fid);
                try
                    runATPsolver(atpsolver,tgtdir,fname);
                catch
                    f = msgbox('Unable to run ATP solver!','You lose, fella.','error');
                    app.ConsoleTextArea.Value=[app.ConsoleTextArea.Value;sprintf('Error occurred while processing section %d!\n',k)];
                    out=false;
                    break;
                end
            end
            app.ConsoleTextArea.Value=[app.ConsoleTextArea.Value;'Computing line parameters... Done!'];
        end
        
        function out = makeAllMainATPFiles(app)
            projID=app.ProjectIDEditField.Value;
            wdir=app.WorkingdirectoryEditField.Value;
            projdir=fullfile(wdir,projID);
            orig_state = warning;warning('off','all');
            mkdir(projdir);
            warning(orig_state);
            fault_sec=getFaultedSections(app);
            app.ConsoleTextArea.Value=[app.ConsoleTextArea.Value;'Making ATP files...'];
            for k=1:length(fault_sec)
                app.ConsoleTextArea.Value=[app.ConsoleTextArea.Value;sprintf('Building fault model for section %d...\n',fault_sec(k))];
                fname=sprintf('%s_fault_sec%d.atp',projID,fault_sec(k));
                fid = fopen(fullfile(projdir,fname),'wt');
                fprintf(fid, makeMainATP(app,fault_sec(k)));
                fclose(fid);
            end
            app.ConsoleTextArea.Value=[app.ConsoleTextArea.Value;'Making ATP files... Done!'];
        end


        function results = setColFormats(app)
            value=app.NumberofsectionsSpinner.Value;
            if value==1
                app.UITableAttSetSec.ColumnFormat{1}={'1'};
                app.UITableAttSetSec.ColumnFormat{2} = {'1'};
                app.UITableFaultSec.ColumnFormat{1}={'1'};
                app.UITableFaultSec.ColumnFormat{2} = {'1'};
                app.UITableSWCurr.ColumnFormat{1}={'1'};
                app.UITableSWCurr.ColumnFormat{2} = {'1'};
                app.UITableTow2Ground.ColumnFormat{1}={'1'};
                app.UITableTow2Ground.ColumnFormat{2} = {'1'};
            else
                app.UITableAttSetSec.ColumnFormat{1} = convertStringsToChars(string(1:value));
                app.UITableAttSetSec.ColumnFormat{2} = convertStringsToChars(string(1:value));
                app.UITableFaultSec.ColumnFormat{1} = convertStringsToChars(string(1:value));
                app.UITableFaultSec.ColumnFormat{2} = convertStringsToChars(string(1:value));
                app.UITableSWCurr.ColumnFormat{1} = convertStringsToChars(string(1:value));
                app.UITableSWCurr.ColumnFormat{2} = convertStringsToChars(string(1:value));
                app.UITableTow2Ground.ColumnFormat{1} = convertStringsToChars(string(1:value));
                app.UITableTow2Ground.ColumnFormat{2} = convertStringsToChars(string(1:value));
            end
            value = app.NumberofattributesetsSpinner.Value;
            if value == 1
                app.UITableAttSetSec.ColumnFormat{3} ={'1'};
            else
                app.UITableAttSetSec.ColumnFormat{3} = convertStringsToChars(string(1:value));
            end
        end
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            setFieldValues(app)
            app.UITableSL.RowName = 'numbered';
            app.UITableAttSets.RowName = 'numbered';
            app.UITableAttSetSec.RowName = 'numbered';
            app.UITableSoilRes.RowName = 'numbered';
            app.UITableGroundImp.RowName = 'numbered';
            app.ConsoleTextArea.Value={'RTFS started.'};
            
            [wdir,~,~] = fileparts(mfilename('fullpath'));
            fname = 'condudb.mat';
            try
                load(fullfile([wdir '\assets'],fname),'condudb_data');
            catch
                uiwait(msgbox('No conductor database found. Please create one before using this app.','You lose, fella.','error'));
                try
                    addpath('assets');
                    condudb_app
                catch
                    f = msgbox('Conductor database app not found!','You lose, fella.','error');
                end
                app.delete;
            end
            
            
        end

        % Button pushed function: LoadWDButton
        function LoadWDButtonPushed(app, event)
            path = uigetdir;
            app.RTFSRealisticTransmissionLineFaultSimulatorUIFigure.Visible = 'off';
            app.RTFSRealisticTransmissionLineFaultSimulatorUIFigure.Visible = 'on';
            if path ~=0
                app.WorkingdirectoryEditField.Value=path;
            end
        end

        % Button pushed function: LoadATPButton
        function LoadATPButtonButtonPushed(app, event)
            [file,path] = uigetfile({'*.exe';'*.bat'});
            app.RTFSRealisticTransmissionLineFaultSimulatorUIFigure.Visible = 'off';
            app.RTFSRealisticTransmissionLineFaultSimulatorUIFigure.Visible = 'on';
            if path ~=0
                app.ATPsolverEditField.Value=fullfile(path,file);
            end
        end

        % Button pushed function: LoadGTPPLOTButton
        function LoadGTPPLOTButtonButtonPushed(app, event)
            [file,path] = uigetfile({'*.exe';'*.bat'});
            app.RTFSRealisticTransmissionLineFaultSimulatorUIFigure.Visible = 'off';
            app.RTFSRealisticTransmissionLineFaultSimulatorUIFigure.Visible = 'on';
            if path ~=0
                app.GTPPLOTEditField.Value=fullfile(path,file);
            end
        end

        % Selection changed function: ConfigurationButtonGroup
        function ConfigurationButtonGroupSelectionChanged(app, event)
            selectedButton = app.ConfigurationButtonGroup.SelectedObject;
            val=selectedButton.Text;
            if strcmp(val,'Double circuit')
                enableCircuit2Fields(app)
            elseif strcmp(val,'Single circuit')
                disableCircuit2Fields(app)
            end
            resetAttSetTable(app);
        end

        % Cell edit callback: UITableSL
        function UITableSLCellEdit(app, event)
            app.TotallinelengthmEditField.Value=sum(cell2mat(app.UITableSL.Data));
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            saveAllData(app);
        end

        % Value changed function: NumberofsectionsSpinner
        function NumberofsectionsSpinnerValueChanged(app, event)
            value = app.NumberofsectionsSpinner.Value;
            if value ~=0
                selectedTransp = app.TranspositionButtonGroup.SelectedObject;
                if value <= 2
                    if strcmp(selectedTransp.Text,'L/3-L/3-L/3') || strcmp(selectedTransp.Text,'L/6-L/3-L/3-L/6')
                        selectedTransp.Value=0;
                        app.TranspositionButtonGroup.SelectedObject=app.UntransposedButton;
                        app.UntransposedButton.Value=1;
                    end
                end
                if value == 3
                    if strcmp(selectedTransp.Text,'L/6-L/3-L/3-L/6')
                        selectedTransp.Value=0;
                        app.TranspositionButtonGroup.SelectedObject=app.L3L3L3Button;
                        app.L3L3L3Button.Value=1;
                    end
                end
                app.UITableSL.Data=cell(value,1);
                val=app.DefaultspanlengthmEditField.Value;
                app.UITableSL.Data(cellfun(@isempty,app.UITableSL.Data)) = num2cell(val);
                app.TotallinelengthmEditField.Value=sum(cell2mat(app.UITableSL.Data));
                app.UITableAttSetSec.Data=cell(value,3);
                valres=app.DefaultSoilResEditField.Value;
                app.UITableSoilRes.Data=cell(value,1);
                app.UITableSoilRes.Data(cellfun(@isempty,app.UITableSoilRes.Data)) = num2cell(valres);
                app.UITableGroundImp.Data=cell(value,3);
                empty_lines=cellfun('isempty',app.UITableGroundImp.Data);
                app.UITableGroundImp.Data(any(empty_lines(:,[1,2,3]),2),:) = num2cell(repmat([app.GroundImpREditField.Value app.GroundImpLEditField.Value app.GroundImpCEditField.Value],value,1));
                app.UITableFaultSec.Data=cell(value,2);
                app.UITableSWCurr.Data=cell(value,2);
                app.UITableTow2Ground.Data=cell(value,2);
                if value==1
                    app.UITableFaultSec.Data(1,:)=num2cell([1 1]);
                    app.UITableSWCurr.Data(1,:)=num2cell([1 1]);
                    app.UITableTow2Ground.Data(1,:)=num2cell([1 1]);
                else
                    app.UITableFaultSec.Data(1,:)=num2cell([1 value]);
                    app.UITableSWCurr.Data(1,:)=num2cell([1 value]);
                    app.UITableTow2Ground.Data(1,:)=num2cell([1 value]);
                end
                setColFormats(app);
            end
        end

        % Value changed function: NumberofattributesetsSpinner
        function NumberofattributesetsSpinnerValueChanged(app, event)
            resetAttSetTable(app);
        end

        % Button pushed function: PlotSoilResButton
        function PlotSoilResButtonPushed(app, event)
            plotdata=cell2mat(app.UITableSoilRes.Data);
            try
                bar(app.UIAxesSoilRes, plotdata);
                app.UIAxesSoilRes.YLim = [0 1.1*max(plotdata)];
            end
        end

        % Value changed function: EnableParaCompCheckBox
        function EnableParaCompCheckBoxValueChanged(app, event)
            value = app.EnableParaCompCheckBox.Value;
            app.workersSpinner.Enable=onIfTrue(app,value);
        end

        % Button pushed function: PlotGroundImpButton
        function PlotGroundImpButtonPushed(app, event)
            plotdata=cell2mat(app.UITableGroundImp.Data(:,1));
            try
                bar(app.UIAxesGroundImp, plotdata);
                app.UIAxesGroundImp.YLim = [0 1.1*max(plotdata)];
            end
        end

        % Value changed function: 
        % OrsectionsbeforeandafterfaultTowShuntParamCheckBox
        function OrsectionsbeforeandafterfaultTowShuntParamCheckBoxValueChanged(app, event)
            value = app.OrsectionsbeforeandafterfaultTowShuntParamCheckBox.Value;
            
            app.SpinnerTow2Ground.Enable=onIfTrue(app,value);
            app.UITableTow2Ground.Enable=onIfTrue(app,~value);
        end

        % Value changed function: 
        % OrsectionsbeforeandafterfaultShWireCurrCheckBox
        function OrsectionsbeforeandafterfaultShWireCurrCheckBoxValueChanged(app, event)
            value = app.OrsectionsbeforeandafterfaultShWireCurrCheckBox.Value;
            app.SpinnerSWCurr.Enable=onIfTrue(app,value);
            app.UITableSWCurr.Enable=onIfTrue(app,~value);
        end

        % Selection changed function: ShieldwiresButtonGroup
        function ShieldwiresButtonGroupSelectionChanged(app, event)
            selectedButton = app.ShieldwiresButtonGroup.SelectedObject;
            value=strcmp(selectedButton.Text,'None');
            
            app.UITableSWCurr.Enable=onIfTrue(app,~value && ~app.OrsectionsbeforeandafterfaultShWireCurrCheckBox.Value);
            app.OrsectionsbeforeandafterfaultShWireCurrCheckBox.Enable=onIfTrue(app,~value);
            app.SpinnerSWCurr.Enable=onIfTrue(app,~value && app.OrsectionsbeforeandafterfaultShWireCurrCheckBox.Value);
            
            app.ShieldwirereturncurrentsCheckBox.Value=~value;
            app.ShieldwirereturncurrentsCheckBox.Enable=onIfTrue(app,~value);
            
            app.OrsectionsbeforeandafterfaultShWireCurrCheckBox.Enable=onIfTrue(app,~value);
            
            resetAttSetTable(app);
        end

        % Value changed function: ShieldwirereturncurrentsCheckBox
        function ShieldwirereturncurrentsCheckBoxValueChanged(app, event)
            value = app.ShieldwirereturncurrentsCheckBox.Value;
            app.UITableSWCurr.Enable=onIfTrue(app,value && ~app.OrsectionsbeforeandafterfaultShWireCurrCheckBox.Value);
            app.OrsectionsbeforeandafterfaultShWireCurrCheckBox.Enable=onIfTrue(app,value);
            app.SpinnerSWCurr.Enable=onIfTrue(app,value && app.OrsectionsbeforeandafterfaultShWireCurrCheckBox.Value);
        end

        % Button pushed function: LoadPl42matButton
        function LoadPl42matButtonPushed(app, event)
            [file,path] = uigetfile('*.exe');
            app.RTFSRealisticTransmissionLineFaultSimulatorUIFigure.Visible = 'off';
            app.RTFSRealisticTransmissionLineFaultSimulatorUIFigure.Visible = 'on';
            if path ~=0
                app.PL42MATEditField.Value=fullfile(path,file);
            end
        end

        % Button pushed function: btnLoadSpanLength
        function btnLoadSpanLengthButtonPushed(app, event)
            [file,path] = uigetfile({'*.txt';'*.csv'});
            app.RTFSRealisticTransmissionLineFaultSimulatorUIFigure.Visible = 'off';
            app.RTFSRealisticTransmissionLineFaultSimulatorUIFigure.Visible = 'on';
            if file ~= 0
                fname=fullfile(path,file);
                nrows=app.NumberofsectionsSpinner.Value;
                ncols=1;
                [fdata,msg]=loadFromFile(app,fname,nrows,ncols);
                
                if isempty(fdata)
                    f = msgbox(msg, 'You lose, fella.','error');
                    return;
                else
                    app.UITableSL.Data=num2cell(fdata);
                    app.TotallinelengthmEditField.Value=sum(cell2mat(app.UITableSL.Data));
                end
                
            end
        end

        % Button pushed function: btnLoadSoilRes
        function btnLoadSoilResButtonPushed(app, event)
            [file,path] = uigetfile({'*.txt';'*.csv'});
            app.RTFSRealisticTransmissionLineFaultSimulatorUIFigure.Visible = 'off';
            app.RTFSRealisticTransmissionLineFaultSimulatorUIFigure.Visible = 'on';
            if file ~= 0
                fname=fullfile(path,file);
                nrows=app.NumberofsectionsSpinner.Value;
                ncols=1;
                [fdata,msg]=loadFromFile(app,fname,nrows,ncols);
                
                if isempty(fdata)
                    f = msgbox(msg, 'You lose, fella.','error');
                    return;
                else
                    app.UITableSoilRes.Data=num2cell(fdata);
                end
            end
        end

        % Button pushed function: btnLoadGroundImp
        function btnLoadGroundImpButtonPushed(app, event)
            [file,path] = uigetfile({'*.txt';'*.csv'});
            app.RTFSRealisticTransmissionLineFaultSimulatorUIFigure.Visible = 'off';
            app.RTFSRealisticTransmissionLineFaultSimulatorUIFigure.Visible = 'on';
            if file ~= 0
                fname=fullfile(path,file);
                nrows=app.NumberofsectionsSpinner.Value;
                ncols=3;
                [fdata,msg]=loadFromFile(app,fname,nrows,ncols);
                
                if isempty(fdata)
                    f = msgbox(msg, 'You lose, fella.','error');
                    return;
                else
                    app.UITableGroundImp.Data=num2cell(fdata);
                end
            end
        end

        % Button pushed function: ConductordatabaseButton
        function ConductordatabaseButtonPushed(app, event)
            try
                addpath('assets');
                condudb_app;
            catch
                f = msgbox('Conductor database app not found!','You lose, fella.','error');
            end
        end

        % Cell selection callback: UITableAttSets
        function UITableAttSetsCellSelection(app, event)
            try
                addpath('assets');
                thisattset=event.Indices(1);
                thisattsetdata=app.UITableAttSets.Data(event.Indices(1),event.Indices(2));
                nphases=valIfTrue(app,app.SinglecircuitButton.Value==1,3,6);
                nshield=valIfTrue(app,app.NoneSWButton.Value==1,0,valIfTrue(app,app.SingleSWButton.Value==1,1,2));
                app.attSetApp = attset_app(app,nphases,nshield,thisattset,thisattsetdata);
            catch
                f = msgbox('Attribute set app error! Verify if the current directory contains the subdirectory ''assets'' and that the file ''condudb_app.(.mlapp)'' exists.','You lose, fella.','error');
            end
        end

        % Selection changed function: TypeButtonGroup
        function TypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.TypeButtonGroup.SelectedObject;
            value=strcmp(selectedButton.Text,'JMarti');
            app.DecadesSpinner.Enable=onIfTrue(app,value);
            app.PointsDecadeSpinner.Enable=onIfTrue(app,value);
            app.FreqmatrixHzEditField.Enable=onIfTrue(app,value);
            app.FreqSSHzEditField.Enable=onIfTrue(app,value);
        end

        % Button pushed function: LoadfromfileButton
        function LoadfromfileButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            app.RTFSRealisticTransmissionLineFaultSimulatorUIFigure.Visible = 'off';
            app.RTFSRealisticTransmissionLineFaultSimulatorUIFigure.Visible = 'on';
            if file ~= 0
                fname=fullfile(path,file);
                try
                    load(fname,'rtfsconf');
                    writeAllAppValues(app,rtfsconf);
                    setColFormats(app);
                catch
                    f = msgbox('Unable to load project file!','You lose, fella.','error');
                    return;
                end
            end
        end

        % Close request function: 
        % RTFSRealisticTransmissionLineFaultSimulatorUIFigure
        function RTFSRealisticTransmissionLineFaultSimulatorUIFigureCloseRequest(app, event)
            delete(app)
            try
                delete(app.attSetApp);
            end
        end

        % Value changed function: ButtonEnableNumSec
        function ButtonEnableNumSecValueChanged(app, event)
            value = app.ButtonEnableNumSec.Value;
            app.NumberofsectionsSpinner.Enable=onIfTrue(app,value);
        end

        % Value changed function: ButtonEnableAttSet
        function ButtonEnableAttSetValueChanged(app, event)
            value = app.ButtonEnableAttSet.Value;
            app.NumberofattributesetsSpinner.Enable=onIfTrue(app,value);
        end

        % Button pushed function: MakeLCCfilesButton
        function MakeLCCfilesButtonPushed(app, event)
            answer = questdlg('Before proceeding with this action, verify that ATP Launcher is properly configured: field ''Close window at end'' must be CHECKED, fields ''Output to screen'' and ''Use work directory'' must be UNCHECKED. Confirm: ', ...
               	'Confirm?', ...
               	'Yes','No','Yes');
            if strcmp(answer,'No')
                return;
            end
            if saveAllData(app)
                %ok now do stuff
                try
                    makeAllLCCFiles(app);
                catch
                    f = msgbox('Unable to process the LCC models. Verify if all attribute sets are properly specified and try again.','You lose, fella.','error');
                end
            end
        end

        % Selection changed function: TranspositionButtonGroup
        function TranspositionButtonGroupSelectionChanged(app, event)
            selectedButton = app.TranspositionButtonGroup.SelectedObject;
            numsec=app.NumberofsectionsSpinner.Value;
            if strcmp(selectedButton.Text,'L/3-L/3-L/3') && selectedButton.Value==1
                if numsec<3
                    f = msgbox('You must specify at least 3 sections to use this transposition scheme.','You lose, fella.','error');
                    selectedButton.Value=0;
                    app.TranspositionButtonGroup.SelectedObject=app.UntransposedButton;
                    app.UntransposedButton.Value=1;
                    return;
                end
            end
            
            if strcmp(selectedButton.Text,'L/6-L/3-L/3-L/6') && selectedButton.Value==1
                if numsec<4
                    f = msgbox('You must specify at least 4 sections to use this transposition scheme.','You lose, fella.','error');
                    selectedButton.Value=0;
                    app.TranspositionButtonGroup.SelectedObject=app.UntransposedButton;
                    app.UntransposedButton.Value=1;
                    return;
                end
            end
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
            try
                delete(app.attSetApp);
            end
        end

        % Button pushed function: MakemainATPfilesButton
        function MakemainATPfilesButtonPushed(app, event)
            %             answer = questdlg('Before proceeding with this action, verify that ATP Launcher is properly configured: field ''Close window at end'' must be CHECKED, fields ''Output to screen'' and ''Use work directory'' must be UNCHECKED. Confirm: ', ...
            %                	'Confirm?', ...
            %                	'Yes','No','Yes');
            %             if strcmp(answer,'No')
            %                 return;
            %             end
            if saveAllData(app)
                numsec=app.NumberofsectionsSpinner.Value;
                wdir=app.WorkingdirectoryEditField.Value;
                projID=app.ProjectIDEditField.Value;
                if size(dir(fullfile(wdir,projID,'LCC','*.pch')),1)==numsec
                    makeAllMainATPFiles(app);
                else
                    f = msgbox('LCC files not found in the working directory. First generate the LCC files and try again.','You lose, fella.','error');
                end
            end
        end

        % Button pushed function: RunATPsolverButton
        function RunATPsolverButtonPushed(app, event)
            answer = questdlg('Before proceeding with this action, verify that ATP Launcher is properly configured: field ''Close window at end'' must be CHECKED, fields ''Output to screen'' and ''Use work directory'' must be UNCHECKED. Confirm: ', ...
               	'Confirm?', ...
               	'Yes','No','Yes');
            if strcmp(answer,'No')
                return;
            end
            if saveAllData(app)
                wdir=app.WorkingdirectoryEditField.Value;
                projID=app.ProjectIDEditField.Value;
                flist=dir(fullfile(wdir,projID,'*.atp'));
                if ~isempty(flist)
                    atpsolver=app.ATPsolverEditField.Value;
                    postproc=app.PL42MATEditField.Value;
                    fault_sec=getFaultedSections(app);
                    addpath('functions');
                    addpath(projID);
                    par=app.EnableParaCompCheckBox.Value;
                    numwork=app.workersSpinner.Value;
                    app.ConsoleTextArea.Value=[app.ConsoleTextArea.Value;'The ATP solver will be launched now. You may monitor progress from the MATLAB Command Window.'];
                    runAllCases(projID,wdir,atpsolver,postproc,fault_sec,par,numwork);
                    app.ConsoleTextArea.Value=[app.ConsoleTextArea.Value;'All done!'];
                else
                    f = msgbox('No ATP files found in the working directory. First generate the circuit models and try again.','You lose, fella.','error');
                end
            end
        end

        % Callback function
        function RUNButtonPushed(app, event)
            answer = questdlg('Before proceeding with this action, verify that ATP Launcher is properly configured: field ''Close window at end'' must be CHECKED, fields ''Output to screen'' and ''Use work directory'' must be UNCHECKED. Confirm: ', ...
               	'Confirm?', ...
               	'Yes','No','Yes');
            if strcmp(answer,'No')
                return;
            end
        end

        % Callback function
        function ClearconsoleButtonPushed(app, event)
            app.ConsoleTextArea.Value={''};
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RTFSRealisticTransmissionLineFaultSimulatorUIFigure = uifigure;
            app.RTFSRealisticTransmissionLineFaultSimulatorUIFigure.Name = 'RTFS - Realistic Transmission Line Fault Simulator';
            app.RTFSRealisticTransmissionLineFaultSimulatorUIFigure.CloseRequestFcn = createCallbackFcn(app, @RTFSRealisticTransmissionLineFaultSimulatorUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.RTFSRealisticTransmissionLineFaultSimulatorUIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.ProjectdataPanel = uipanel(app.GeneralTab);
            app.ProjectdataPanel.Title = 'Project data';
            app.LoadWDButton = uibutton(app.ProjectdataPanel, 'push');
            app.LoadWDButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWDButtonPushed, true);
            app.LoadWDButton.Text = '';
            app.ProjectIDEditFieldLabel = uilabel(app.ProjectdataPanel);
            app.ProjectIDEditFieldLabel.Text = 'Project ID:';
            app.ProjectIDEditField = uieditfield(app.ProjectdataPanel, 'text');
            app.WorkingdirectoryEditFieldLabel = uilabel(app.ProjectdataPanel);
            app.WorkingdirectoryEditFieldLabel.Text = 'Working directory:';
            app.WorkingdirectoryEditField = uieditfield(app.ProjectdataPanel, 'text');
            app.ProjectdescriptionTextAreaLabel = uilabel(app.ProjectdataPanel);
            app.ProjectdescriptionTextAreaLabel.Text = 'Project description:';
            app.ProjectdescriptionTextArea = uitextarea(app.ProjectdataPanel);
            app.PathstoexecutablesPanel = uipanel(app.GeneralTab);
            app.PathstoexecutablesPanel.Title = 'Paths to executables';
            app.LoadATPButton = uibutton(app.PathstoexecutablesPanel, 'push');
            app.LoadATPButton.ButtonPushedFcn = createCallbackFcn(app, @LoadATPButtonButtonPushed, true);
            app.LoadATPButton.Text = '';
            app.LoadGTPPLOTButton = uibutton(app.PathstoexecutablesPanel, 'push');
            app.LoadGTPPLOTButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGTPPLOTButtonButtonPushed, true);
            app.LoadGTPPLOTButton.Text = '';
            app.ATPsolverEditFieldLabel = uilabel(app.PathstoexecutablesPanel);
            app.ATPsolverEditFieldLabel.Text = 'ATP solver:';
            app.ATPsolverEditField = uieditfield(app.PathstoexecutablesPanel, 'text');
            app.ATPsolverEditField.Value = '%ATPPATH%\tools\runATP.exe';
            app.GTPPLOTEditFieldLabel = uilabel(app.PathstoexecutablesPanel);
            app.GTPPLOTEditFieldLabel.Text = 'GTPPLOT:';
            app.GTPPLOTEditField = uieditfield(app.PathstoexecutablesPanel, 'text');
            app.GTPPLOTEditField.Value = '%ATPPATH%\tools\GTPPL32.exe';
            app.LoadPl42matButton = uibutton(app.PathstoexecutablesPanel, 'push');
            app.LoadPl42matButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPl42matButtonPushed, true);
            app.LoadPl42matButton.Text = '';
            app.PL42MATEditFieldLabel = uilabel(app.PathstoexecutablesPanel);
            app.PL42MATEditFieldLabel.Text = 'PL42MAT:';
            app.PL42MATEditField = uieditfield(app.PathstoexecutablesPanel, 'text');
            app.PL42MATEditField.Value = '%ATPPATH%\tools\Pl42mat.exe';
            app.ATPsettingsPanel = uipanel(app.GeneralTab);
            app.ATPsettingsPanel.Title = 'ATP settings';
            app.FreqHzEditFieldLabel = uilabel(app.ATPsettingsPanel);
            app.FreqHzEditFieldLabel.Text = 'Freq. [Hz]:';
            app.FreqHzEditField = uieditfield(app.ATPsettingsPanel, 'numeric');
            app.FreqHzEditField.Limits = [0 Inf];
            app.FreqHzEditField.Value = 60;
            app.deltaTsEditFieldLabel = uilabel(app.ATPsettingsPanel);
            app.deltaTsEditFieldLabel.Text = 'delta T [s]:';
            app.deltaTsEditField = uieditfield(app.ATPsettingsPanel, 'numeric');
            app.deltaTsEditField.Limits = [0 Inf];
            app.deltaTsEditField.Value = 1e-06;
            app.TmaxsEditFieldLabel = uilabel(app.ATPsettingsPanel);
            app.TmaxsEditFieldLabel.Text = 'Tmax [s]:';
            app.TmaxsEditField = uieditfield(app.ATPsettingsPanel, 'numeric');
            app.TmaxsEditField.Limits = [0 100];
            app.TmaxsEditField.Value = 0.1;
            app.ParallelcomputingPanel = uipanel(app.GeneralTab);
            app.ParallelcomputingPanel.Title = 'Parallel computing';
            app.EnableParaCompCheckBox = uicheckbox(app.ParallelcomputingPanel);
            app.EnableParaCompCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableParaCompCheckBoxValueChanged, true);
            app.EnableParaCompCheckBox.Text = 'Enable';
            app.workersSpinnerLabel = uilabel(app.ParallelcomputingPanel);
            app.workersSpinnerLabel.Text = '# workers:';
            app.workersSpinner = uispinner(app.ParallelcomputingPanel);
            app.workersSpinner.Limits = [1 Inf];
            app.workersSpinner.RoundFractionalValues = 'on';
            app.workersSpinner.ValueDisplayFormat = '%.0f';
            app.workersSpinner.Enable = 'off';
            app.workersSpinner.Value = 12;
            app.LinemodelTab = uitab(app.TabGroup);
            app.LinemodelTab.Title = 'Line model';
            app.TypeButtonGroup = uibuttongroup(app.LinemodelTab);
            app.TypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeButtonGroupSelectionChanged, true);
            app.TypeButtonGroup.Title = 'Type';
            app.BergeronButton = uiradiobutton(app.TypeButtonGroup);
            app.BergeronButton.Text = 'Bergeron';
            app.BergeronButton.Value = true;
            app.PiButton = uiradiobutton(app.TypeButtonGroup);
            app.PiButton.Text = 'Pi';
            app.JMartiButton = uiradiobutton(app.TypeButtonGroup);
            app.JMartiButton.Text = 'JMarti';
            app.ConfigurationButtonGroup = uibuttongroup(app.LinemodelTab);
            app.ConfigurationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConfigurationButtonGroupSelectionChanged, true);
            app.ConfigurationButtonGroup.Title = 'Configuration';
            app.SinglecircuitButton = uiradiobutton(app.ConfigurationButtonGroup);
            app.SinglecircuitButton.Text = 'Single circuit';
            app.SinglecircuitButton.Value = true;
            app.DoublecircuitButton = uiradiobutton(app.ConfigurationButtonGroup);
            app.DoublecircuitButton.Text = 'Double circuit';
            app.TranspositionButtonGroup = uibuttongroup(app.LinemodelTab);
            app.TranspositionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TranspositionButtonGroupSelectionChanged, true);
            app.TranspositionButtonGroup.Title = 'Transposition';
            app.UntransposedButton = uiradiobutton(app.TranspositionButtonGroup);
            app.UntransposedButton.Text = 'Untransposed';
            app.UntransposedButton.Value = true;
            app.L3L3L3Button = uiradiobutton(app.TranspositionButtonGroup);
            app.L3L3L3Button.Text = 'L/3-L/3-L/3';
            app.L6L3L3L6Button = uiradiobutton(app.TranspositionButtonGroup);
            app.L6L3L3L6Button.Text = 'L/6-L/3-L/3-L/6';
            app.DefaultspanlengthmEditFieldLabel = uilabel(app.LinemodelTab);
            app.DefaultspanlengthmEditFieldLabel.Text = 'Default span length [m]:';
            app.DefaultspanlengthmEditField = uieditfield(app.LinemodelTab, 'numeric');
            app.DefaultspanlengthmEditField.Limits = [0 Inf];
            app.DefaultspanlengthmEditField.Value = 100;
            app.UITableSL = uitable(app.LinemodelTab);
            app.UITableSL.ColumnName = {'Span length [m]'};
            app.UITableSL.RowName = {};
            app.UITableSL.ColumnEditable = true;
            app.UITableSL.CellEditCallback = createCallbackFcn(app, @UITableSLCellEdit, true);
            app.SpecifyindividualsectionsLabel = uilabel(app.LinemodelTab);
            app.SpecifyindividualsectionsLabel.Text = 'Specify individual sections:';
            app.LCCfrequencyHzEditFieldLabel = uilabel(app.LinemodelTab);
            app.LCCfrequencyHzEditFieldLabel.Text = 'LCC frequency [Hz]:';
            app.LCCfrequencyHzEditField = uieditfield(app.LinemodelTab, 'numeric');
            app.LCCfrequencyHzEditField.Limits = [0 Inf];
            app.LCCfrequencyHzEditField.Value = 60;
            app.btnLoadSpanLength = uibutton(app.LinemodelTab, 'push');
            app.btnLoadSpanLength.ButtonPushedFcn = createCallbackFcn(app, @btnLoadSpanLengthButtonPushed, true);
            app.btnLoadSpanLength.Text = '';
            app.ShieldwiresButtonGroup = uibuttongroup(app.LinemodelTab);
            app.ShieldwiresButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ShieldwiresButtonGroupSelectionChanged, true);
            app.ShieldwiresButtonGroup.Title = 'Shield wires';
            app.SingleSWButton = uiradiobutton(app.ShieldwiresButtonGroup);
            app.SingleSWButton.Text = 'Single';
            app.SingleSWButton.Value = true;
            app.DoubleSWButton = uiradiobutton(app.ShieldwiresButtonGroup);
            app.DoubleSWButton.Text = 'Double';
            app.NoneSWButton = uiradiobutton(app.ShieldwiresButtonGroup);
            app.NoneSWButton.Text = 'None';
            app.TotallinelengthmEditFieldLabel = uilabel(app.LinemodelTab);
            app.TotallinelengthmEditFieldLabel.Text = 'Total line length [m]:';
            app.TotallinelengthmEditField = uieditfield(app.LinemodelTab, 'numeric');
            app.TotallinelengthmEditField.Editable = 'off';
            app.NumberofsectionsSpinnerLabel = uilabel(app.LinemodelTab);
            app.NumberofsectionsSpinnerLabel.Text = 'Number of sections:';
            app.NumberofsectionsSpinner = uispinner(app.LinemodelTab);
            app.NumberofsectionsSpinner.Limits = [1 9999];
            app.NumberofsectionsSpinner.RoundFractionalValues = 'on';
            app.NumberofsectionsSpinner.ValueDisplayFormat = '%.0f';
            app.NumberofsectionsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofsectionsSpinnerValueChanged, true);
            app.NumberofsectionsSpinner.Enable = 'off';
            app.NumberofsectionsSpinner.Value = 1;
            app.JMartimodelparametersPanel = uipanel(app.LinemodelTab);
            app.JMartimodelparametersPanel.Title = 'JMarti model parameters';
            app.UsedefaultfittingCheckBox = uicheckbox(app.JMartimodelparametersPanel);
            app.UsedefaultfittingCheckBox.Enable = 'off';
            app.UsedefaultfittingCheckBox.Text = 'Use default fitting';
            app.UsedefaultfittingCheckBox.Value = true;
            app.DecadesSpinnerLabel = uilabel(app.JMartimodelparametersPanel);
            app.DecadesSpinnerLabel.Text = 'Decades';
            app.DecadesSpinner = uispinner(app.JMartimodelparametersPanel);
            app.DecadesSpinner.Limits = [1 99];
            app.DecadesSpinner.RoundFractionalValues = 'on';
            app.DecadesSpinner.ValueDisplayFormat = '%.0f';
            app.DecadesSpinner.Enable = 'off';
            app.DecadesSpinner.Value = 6;
            app.PointsDecadeSpinnerLabel = uilabel(app.JMartimodelparametersPanel);
            app.PointsDecadeSpinnerLabel.Text = 'Points/Decade';
            app.PointsDecadeSpinner = uispinner(app.JMartimodelparametersPanel);
            app.PointsDecadeSpinner.Limits = [1 99];
            app.PointsDecadeSpinner.RoundFractionalValues = 'on';
            app.PointsDecadeSpinner.ValueDisplayFormat = '%.0f';
            app.PointsDecadeSpinner.Enable = 'off';
            app.PointsDecadeSpinner.Value = 10;
            app.FreqmatrixHzEditFieldLabel = uilabel(app.JMartimodelparametersPanel);
            app.FreqmatrixHzEditFieldLabel.Text = 'Freq. matrix [Hz]:';
            app.FreqmatrixHzEditField = uieditfield(app.JMartimodelparametersPanel, 'numeric');
            app.FreqmatrixHzEditField.Limits = [0 Inf];
            app.FreqmatrixHzEditField.Enable = 'off';
            app.FreqmatrixHzEditField.Value = 10000;
            app.FreqSSHzEditFieldLabel = uilabel(app.JMartimodelparametersPanel);
            app.FreqSSHzEditFieldLabel.Text = 'Freq. SS [Hz]:';
            app.FreqSSHzEditField = uieditfield(app.JMartimodelparametersPanel, 'numeric');
            app.FreqSSHzEditField.Limits = [0 Inf];
            app.FreqSSHzEditField.Enable = 'off';
            app.FreqSSHzEditField.Value = 60;
            app.ButtonEnableNumSec = uibutton(app.LinemodelTab, 'state');
            app.ButtonEnableNumSec.ValueChangedFcn = createCallbackFcn(app, @ButtonEnableNumSecValueChanged, true);
            app.ButtonEnableNumSec.Text = '✏';
            app.CrosssectionTab = uitab(app.TabGroup);
            app.CrosssectionTab.Title = 'Cross section';
            app.DefaultattributesetDropDownLabel = uilabel(app.CrosssectionTab);
            app.DefaultattributesetDropDownLabel.Text = 'Default attribute set:';
            app.DefaultattributesetDropDown = uidropdown(app.CrosssectionTab);
            app.DefaultattributesetDropDown.Items = {'1'};
            app.DefaultattributesetDropDown.Value = '1';
            app.UITableAttSets = uitable(app.CrosssectionTab);
            app.UITableAttSets.ColumnName = {'Attribute sets'};
            app.UITableAttSets.RowName = {};
            app.UITableAttSets.CellSelectionCallback = createCallbackFcn(app, @UITableAttSetsCellSelection, true);
            app.DefineeachattributesetclicktovieweditLabel = uilabel(app.CrosssectionTab);
            app.DefineeachattributesetclicktovieweditLabel.Text = 'Define each attribute set (click to view/edit):';
            app.UITableAttSetSec = uitable(app.CrosssectionTab);
            app.UITableAttSetSec.ColumnName = {'From'; 'To'; 'Att. set'};
            app.UITableAttSetSec.RowName = {};
            app.UITableAttSetSec.ColumnEditable = true;
            app.SpecifyattributesetsforindividualsectionsLabel = uilabel(app.CrosssectionTab);
            app.SpecifyattributesetsforindividualsectionsLabel.Text = 'Specify attribute sets for individual sections:';
            app.NumberofattributesetsSpinnerLabel = uilabel(app.CrosssectionTab);
            app.NumberofattributesetsSpinnerLabel.Text = 'Number of attribute sets:';
            app.NumberofattributesetsSpinner = uispinner(app.CrosssectionTab);
            app.NumberofattributesetsSpinner.Limits = [1 Inf];
            app.NumberofattributesetsSpinner.RoundFractionalValues = 'on';
            app.NumberofattributesetsSpinner.ValueDisplayFormat = '%.0f';
            app.NumberofattributesetsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofattributesetsSpinnerValueChanged, true);
            app.NumberofattributesetsSpinner.Enable = 'off';
            app.NumberofattributesetsSpinner.Value = 1;
            app.ButtonEnableAttSet = uibutton(app.CrosssectionTab, 'state');
            app.ButtonEnableAttSet.ValueChangedFcn = createCallbackFcn(app, @ButtonEnableAttSetValueChanged, true);
            app.ButtonEnableAttSet.Text = '✏';
            app.Terminal1Tab = uitab(app.TabGroup);
            app.Terminal1Tab.Title = 'Terminal 1';
            app.Circuit1dataPanel = uipanel(app.Terminal1Tab);
            app.Circuit1dataPanel.Title = 'Circuit 1 data';
            app.TabGroup2 = uitabgroup(app.Circuit1dataPanel);
            app.VoltagesourceTab = uitab(app.TabGroup2);
            app.VoltagesourceTab.Title = 'Voltage source';
            app.RMSLLamplitudeVEditFieldLabel = uilabel(app.VoltagesourceTab);
            app.RMSLLamplitudeVEditFieldLabel.Text = 'RMS LL amplitude [V]:';
            app.RMSLLamplitudeV1EditFieldT1 = uieditfield(app.VoltagesourceTab, 'numeric');
            app.RMSLLamplitudeV1EditFieldT1.Limits = [0 Inf];
            app.FrequencyHzLabel = uilabel(app.VoltagesourceTab);
            app.FrequencyHzLabel.Text = 'Frequency [Hz]:';
            app.FrequencyHz1EditFieldT1 = uieditfield(app.VoltagesourceTab, 'numeric');
            app.FrequencyHz1EditFieldT1.Limits = [0 Inf];
            app.FrequencyHz1EditFieldT1.Value = 60;
            app.PhaseangledegLabel = uilabel(app.VoltagesourceTab);
            app.PhaseangledegLabel.Text = 'Phase angle [deg]:';
            app.Phaseangledeg1EditFieldT1 = uieditfield(app.VoltagesourceTab, 'numeric');
            app.TstartsLabel = uilabel(app.VoltagesourceTab);
            app.TstartsLabel.Text = 'Tstart [s]:';
            app.Tstarts1EditFieldT1 = uieditfield(app.VoltagesourceTab, 'numeric');
            app.Tstarts1EditFieldT1.Limits = [-1 100];
            app.Tstarts1EditFieldT1.Value = -1;
            app.TstopsLabel = uilabel(app.VoltagesourceTab);
            app.TstopsLabel.Text = 'Tstop [s]:';
            app.Tstops1EditFieldT1 = uieditfield(app.VoltagesourceTab, 'numeric');
            app.Tstops1EditFieldT1.Limits = [-1 100];
            app.Tstops1EditFieldT1.Value = 100;
            app.ImpedancesTab = uitab(app.TabGroup2);
            app.ImpedancesTab.Title = 'Impedances';
            app.RLabel = uilabel(app.ImpedancesTab);
            app.RLabel.Text = 'R [Ω]';
            app.LmHLabel = uilabel(app.ImpedancesTab);
            app.LmHLabel.Text = 'L [mH]';
            app.CFLabel = uilabel(app.ImpedancesTab);
            app.CFLabel.Text = 'C [µF]';
            app.GroundinggridLabel = uilabel(app.ImpedancesTab);
            app.GroundinggridLabel.Text = 'Grounding grid:';
            app.RGG1EditFieldT1 = uieditfield(app.ImpedancesTab, 'numeric');
            app.RGG1EditFieldT1.Limits = [1e-05 Inf];
            app.RGG1EditFieldT1.Value = 1e-05;
            app.LGG1EditFieldT1 = uieditfield(app.ImpedancesTab, 'numeric');
            app.LGG1EditFieldT1.Limits = [0 Inf];
            app.CGG1EditFieldT1 = uieditfield(app.ImpedancesTab, 'numeric');
            app.CGG1EditFieldT1.Limits = [0 Inf];
            app.NeutralconnectionLabel = uilabel(app.ImpedancesTab);
            app.NeutralconnectionLabel.Text = 'Neutral connection:';
            app.RNC1EditFieldT1 = uieditfield(app.ImpedancesTab, 'numeric');
            app.RNC1EditFieldT1.Limits = [1e-05 Inf];
            app.RNC1EditFieldT1.Value = 1e-05;
            app.LNC1EditFieldT1 = uieditfield(app.ImpedancesTab, 'numeric');
            app.LNC1EditFieldT1.Limits = [0 Inf];
            app.CNC1EditFieldT1 = uieditfield(app.ImpedancesTab, 'numeric');
            app.CNC1EditFieldT1.Limits = [0 Inf];
            app.LmHLabel_2 = uilabel(app.ImpedancesTab);
            app.LmHLabel_2.Text = 'L [mH]';
            app.RLabel_2 = uilabel(app.ImpedancesTab);
            app.RLabel_2.Text = 'R [Ω]';
            app.Zerosequence0Label = uilabel(app.ImpedancesTab);
            app.Zerosequence0Label.Text = 'Zero sequence (0):';
            app.R0S1EditFieldT1 = uieditfield(app.ImpedancesTab, 'numeric');
            app.R0S1EditFieldT1.Limits = [1e-05 Inf];
            app.R0S1EditFieldT1.Value = 1e-05;
            app.L0S1EditFieldT1 = uieditfield(app.ImpedancesTab, 'numeric');
            app.L0S1EditFieldT1.Limits = [0 Inf];
            app.PositivesequenceLabel = uilabel(app.ImpedancesTab);
            app.PositivesequenceLabel.Text = 'Positive sequence (+):';
            app.R1S1EditFieldT1 = uieditfield(app.ImpedancesTab, 'numeric');
            app.R1S1EditFieldT1.Limits = [1e-05 Inf];
            app.R1S1EditFieldT1.Value = 1e-05;
            app.L1S1EditFieldT1 = uieditfield(app.ImpedancesTab, 'numeric');
            app.L1S1EditFieldT1.Limits = [0 Inf];
            app.ThveninEqLabel = uilabel(app.ImpedancesTab);
            app.ThveninEqLabel.Text = 'Thévenin Eq.';
            app.SubstationLabel = uilabel(app.ImpedancesTab);
            app.SubstationLabel.Text = 'Substation';
            app.Circuit2dataPanel = uipanel(app.Terminal1Tab);
            app.Circuit2dataPanel.Title = 'Circuit 2 data';
            app.TabGroup2_2 = uitabgroup(app.Circuit2dataPanel);
            app.VoltagesourceTab_2 = uitab(app.TabGroup2_2);
            app.VoltagesourceTab_2.Title = 'Voltage source';
            app.RMSLLamplitudeVEditFieldLabel_2 = uilabel(app.VoltagesourceTab_2);
            app.RMSLLamplitudeVEditFieldLabel_2.Text = 'RMS LL amplitude [V]:';
            app.RMSLLamplitudeV2EditFieldT1 = uieditfield(app.VoltagesourceTab_2, 'numeric');
            app.RMSLLamplitudeV2EditFieldT1.Limits = [0 Inf];
            app.RMSLLamplitudeV2EditFieldT1.Enable = 'off';
            app.FrequencyHzLabel_2 = uilabel(app.VoltagesourceTab_2);
            app.FrequencyHzLabel_2.Text = 'Frequency [Hz]:';
            app.FrequencyHz2EditFieldT1 = uieditfield(app.VoltagesourceTab_2, 'numeric');
            app.FrequencyHz2EditFieldT1.Limits = [0 Inf];
            app.FrequencyHz2EditFieldT1.Enable = 'off';
            app.FrequencyHz2EditFieldT1.Value = 60;
            app.PhaseangledegLabel_2 = uilabel(app.VoltagesourceTab_2);
            app.PhaseangledegLabel_2.Text = 'Phase angle [deg]:';
            app.Phaseangledeg2EditFieldT1 = uieditfield(app.VoltagesourceTab_2, 'numeric');
            app.Phaseangledeg2EditFieldT1.Enable = 'off';
            app.TstartsLabel_2 = uilabel(app.VoltagesourceTab_2);
            app.TstartsLabel_2.Text = 'Tstart [s]:';
            app.Tstarts2EditFieldT1 = uieditfield(app.VoltagesourceTab_2, 'numeric');
            app.Tstarts2EditFieldT1.Limits = [-1 100];
            app.Tstarts2EditFieldT1.Enable = 'off';
            app.Tstarts2EditFieldT1.Value = -1;
            app.TstopsLabel_2 = uilabel(app.VoltagesourceTab_2);
            app.TstopsLabel_2.Text = 'Tstop [s]:';
            app.Tstops2EditFieldT1 = uieditfield(app.VoltagesourceTab_2, 'numeric');
            app.Tstops2EditFieldT1.Limits = [-1 100];
            app.Tstops2EditFieldT1.Enable = 'off';
            app.Tstops2EditFieldT1.Value = 100;
            app.ImpedancesTab_2 = uitab(app.TabGroup2_2);
            app.ImpedancesTab_2.Title = 'Impedances';
            app.RLabel_3 = uilabel(app.ImpedancesTab_2);
            app.RLabel_3.Text = 'R [Ω]';
            app.LmHLabel_3 = uilabel(app.ImpedancesTab_2);
            app.LmHLabel_3.Text = 'L [mH]';
            app.CFLabel_2 = uilabel(app.ImpedancesTab_2);
            app.CFLabel_2.Text = 'C [µF]';
            app.GroundinggridLabel_2 = uilabel(app.ImpedancesTab_2);
            app.GroundinggridLabel_2.Text = 'Grounding grid:';
            app.RGG2EditFieldT1 = uieditfield(app.ImpedancesTab_2, 'numeric');
            app.RGG2EditFieldT1.Limits = [1e-05 Inf];
            app.RGG2EditFieldT1.Enable = 'off';
            app.RGG2EditFieldT1.Value = 1e-05;
            app.LGG2EditFieldT1 = uieditfield(app.ImpedancesTab_2, 'numeric');
            app.LGG2EditFieldT1.Limits = [0 Inf];
            app.LGG2EditFieldT1.Enable = 'off';
            app.CGG2EditFieldT1 = uieditfield(app.ImpedancesTab_2, 'numeric');
            app.CGG2EditFieldT1.Limits = [0 Inf];
            app.CGG2EditFieldT1.Enable = 'off';
            app.NeutralconnectionLabel_2 = uilabel(app.ImpedancesTab_2);
            app.NeutralconnectionLabel_2.Text = 'Neutral connection:';
            app.RNC2EditFieldT1 = uieditfield(app.ImpedancesTab_2, 'numeric');
            app.RNC2EditFieldT1.Limits = [1e-05 Inf];
            app.RNC2EditFieldT1.Enable = 'off';
            app.RNC2EditFieldT1.Value = 1e-05;
            app.LNC2EditFieldT1 = uieditfield(app.ImpedancesTab_2, 'numeric');
            app.LNC2EditFieldT1.Limits = [0 Inf];
            app.LNC2EditFieldT1.Enable = 'off';
            app.CNC2EditFieldT1 = uieditfield(app.ImpedancesTab_2, 'numeric');
            app.CNC2EditFieldT1.Limits = [0 Inf];
            app.CNC2EditFieldT1.Enable = 'off';
            app.LmHLabel_4 = uilabel(app.ImpedancesTab_2);
            app.LmHLabel_4.Text = 'L [mH]';
            app.RLabel_4 = uilabel(app.ImpedancesTab_2);
            app.RLabel_4.Text = 'R [Ω]';
            app.Zerosequence0Label_2 = uilabel(app.ImpedancesTab_2);
            app.Zerosequence0Label_2.Text = 'Zero sequence (0):';
            app.R0S2EditFieldT1 = uieditfield(app.ImpedancesTab_2, 'numeric');
            app.R0S2EditFieldT1.Limits = [1e-05 Inf];
            app.R0S2EditFieldT1.Enable = 'off';
            app.R0S2EditFieldT1.Value = 1e-05;
            app.L0S2EditFieldT1 = uieditfield(app.ImpedancesTab_2, 'numeric');
            app.L0S2EditFieldT1.Limits = [0 Inf];
            app.L0S2EditFieldT1.Enable = 'off';
            app.PositivesequenceLabel_2 = uilabel(app.ImpedancesTab_2);
            app.PositivesequenceLabel_2.Text = 'Positive sequence (+):';
            app.R1S2EditFieldT1 = uieditfield(app.ImpedancesTab_2, 'numeric');
            app.R1S2EditFieldT1.Limits = [1e-05 Inf];
            app.R1S2EditFieldT1.Enable = 'off';
            app.R1S2EditFieldT1.Value = 1e-05;
            app.L1S2EditFieldT1 = uieditfield(app.ImpedancesTab_2, 'numeric');
            app.L1S2EditFieldT1.Limits = [0 Inf];
            app.L1S2EditFieldT1.Enable = 'off';
            app.ThveninEqLabel_2 = uilabel(app.ImpedancesTab_2);
            app.ThveninEqLabel_2.Text = 'Thévenin Eq.';
            app.SubstationLabel_2 = uilabel(app.ImpedancesTab_2);
            app.SubstationLabel_2.Text = 'Substation';
            app.Terminal2Tab = uitab(app.TabGroup);
            app.Terminal2Tab.Title = 'Terminal 2';
            app.Circuit1dataPanel_2 = uipanel(app.Terminal2Tab);
            app.Circuit1dataPanel_2.Title = 'Circuit 1 data';
            app.TabGroup2_3 = uitabgroup(app.Circuit1dataPanel_2);
            app.VoltagesourceTab_3 = uitab(app.TabGroup2_3);
            app.VoltagesourceTab_3.Title = 'Voltage source';
            app.RMSLLamplitudeVEditFieldLabel_3 = uilabel(app.VoltagesourceTab_3);
            app.RMSLLamplitudeVEditFieldLabel_3.Text = 'RMS LL amplitude [V]:';
            app.RMSLLamplitudeV1EditFieldT2 = uieditfield(app.VoltagesourceTab_3, 'numeric');
            app.RMSLLamplitudeV1EditFieldT2.Limits = [0 Inf];
            app.FrequencyHzLabel_3 = uilabel(app.VoltagesourceTab_3);
            app.FrequencyHzLabel_3.Text = 'Frequency [Hz]:';
            app.FrequencyHz1EditFieldT2 = uieditfield(app.VoltagesourceTab_3, 'numeric');
            app.FrequencyHz1EditFieldT2.Limits = [0 Inf];
            app.FrequencyHz1EditFieldT2.Value = 60;
            app.PhaseangledegLabel_3 = uilabel(app.VoltagesourceTab_3);
            app.PhaseangledegLabel_3.Text = 'Phase angle [deg]:';
            app.Phaseangledeg1EditFieldT2 = uieditfield(app.VoltagesourceTab_3, 'numeric');
            app.TstartsLabel_3 = uilabel(app.VoltagesourceTab_3);
            app.TstartsLabel_3.Text = 'Tstart [s]:';
            app.Tstarts1EditFieldT2 = uieditfield(app.VoltagesourceTab_3, 'numeric');
            app.Tstarts1EditFieldT2.Limits = [-1 100];
            app.Tstarts1EditFieldT2.Value = -1;
            app.TstopsLabel_3 = uilabel(app.VoltagesourceTab_3);
            app.TstopsLabel_3.Text = 'Tstop [s]:';
            app.Tstops1EditFieldT2 = uieditfield(app.VoltagesourceTab_3, 'numeric');
            app.Tstops1EditFieldT2.Limits = [-1 100];
            app.Tstops1EditFieldT2.Value = 100;
            app.ImpedancesTab_3 = uitab(app.TabGroup2_3);
            app.ImpedancesTab_3.Title = 'Impedances';
            app.RLabel_5 = uilabel(app.ImpedancesTab_3);
            app.RLabel_5.Text = 'R [Ω]';
            app.LmHLabel_5 = uilabel(app.ImpedancesTab_3);
            app.LmHLabel_5.Text = 'L [mH]';
            app.CFLabel_3 = uilabel(app.ImpedancesTab_3);
            app.CFLabel_3.Text = 'C [µF]';
            app.GroundinggridLabel_3 = uilabel(app.ImpedancesTab_3);
            app.GroundinggridLabel_3.Text = 'Grounding grid:';
            app.RGG1EditFieldT2 = uieditfield(app.ImpedancesTab_3, 'numeric');
            app.RGG1EditFieldT2.Limits = [1e-05 Inf];
            app.RGG1EditFieldT2.Value = 1e-05;
            app.LGG1EditFieldT2 = uieditfield(app.ImpedancesTab_3, 'numeric');
            app.LGG1EditFieldT2.Limits = [0 Inf];
            app.CGG1EditFieldT2 = uieditfield(app.ImpedancesTab_3, 'numeric');
            app.CGG1EditFieldT2.Limits = [0 Inf];
            app.NeutralconnectionLabel_3 = uilabel(app.ImpedancesTab_3);
            app.NeutralconnectionLabel_3.Text = 'Neutral connection:';
            app.RNC1EditFieldT2 = uieditfield(app.ImpedancesTab_3, 'numeric');
            app.RNC1EditFieldT2.Limits = [1e-05 Inf];
            app.RNC1EditFieldT2.Value = 1e-05;
            app.LNC1EditFieldT2 = uieditfield(app.ImpedancesTab_3, 'numeric');
            app.LNC1EditFieldT2.Limits = [0 Inf];
            app.CNC1EditFieldT2 = uieditfield(app.ImpedancesTab_3, 'numeric');
            app.CNC1EditFieldT2.Limits = [0 Inf];
            app.LmHLabel_6 = uilabel(app.ImpedancesTab_3);
            app.LmHLabel_6.Text = 'L [mH]';
            app.RLabel_6 = uilabel(app.ImpedancesTab_3);
            app.RLabel_6.Text = 'R [Ω]';
            app.Zerosequence0Label_3 = uilabel(app.ImpedancesTab_3);
            app.Zerosequence0Label_3.Text = 'Zero sequence (0):';
            app.R0S1EditFieldT2 = uieditfield(app.ImpedancesTab_3, 'numeric');
            app.R0S1EditFieldT2.Limits = [1e-05 Inf];
            app.R0S1EditFieldT2.Value = 1e-05;
            app.L0S1EditFieldT2 = uieditfield(app.ImpedancesTab_3, 'numeric');
            app.L0S1EditFieldT2.Limits = [0 Inf];
            app.PositivesequenceLabel_3 = uilabel(app.ImpedancesTab_3);
            app.PositivesequenceLabel_3.Text = 'Positive sequence (+):';
            app.R1S1EditFieldT2 = uieditfield(app.ImpedancesTab_3, 'numeric');
            app.R1S1EditFieldT2.Limits = [1e-05 Inf];
            app.R1S1EditFieldT2.Value = 1e-05;
            app.L1S1EditFieldT2 = uieditfield(app.ImpedancesTab_3, 'numeric');
            app.L1S1EditFieldT2.Limits = [0 Inf];
            app.ThveninEqLabel_3 = uilabel(app.ImpedancesTab_3);
            app.ThveninEqLabel_3.Text = 'Thévenin Eq.';
            app.SubstationLabel_3 = uilabel(app.ImpedancesTab_3);
            app.SubstationLabel_3.Text = 'Substation';
            app.Circuit2dataPanel_2 = uipanel(app.Terminal2Tab);
            app.Circuit2dataPanel_2.Title = 'Circuit 2 data';
            app.TabGroup2_4 = uitabgroup(app.Circuit2dataPanel_2);
            app.VoltagesourceTab_4 = uitab(app.TabGroup2_4);
            app.VoltagesourceTab_4.Title = 'Voltage source';
            app.RMSLLamplitudeVEditFieldLabel_4 = uilabel(app.VoltagesourceTab_4);
            app.RMSLLamplitudeVEditFieldLabel_4.Text = 'RMS LL amplitude [V]:';
            app.RMSLLamplitudeV2EditFieldT2 = uieditfield(app.VoltagesourceTab_4, 'numeric');
            app.RMSLLamplitudeV2EditFieldT2.Limits = [0 Inf];
            app.RMSLLamplitudeV2EditFieldT2.Enable = 'off';
            app.FrequencyHzLabel_4 = uilabel(app.VoltagesourceTab_4);
            app.FrequencyHzLabel_4.Text = 'Frequency [Hz]:';
            app.FrequencyHz2EditFieldT2 = uieditfield(app.VoltagesourceTab_4, 'numeric');
            app.FrequencyHz2EditFieldT2.Limits = [0 Inf];
            app.FrequencyHz2EditFieldT2.Enable = 'off';
            app.FrequencyHz2EditFieldT2.Value = 60;
            app.PhaseangledegLabel_4 = uilabel(app.VoltagesourceTab_4);
            app.PhaseangledegLabel_4.Text = 'Phase angle [deg]:';
            app.Phaseangledeg2EditFieldT2 = uieditfield(app.VoltagesourceTab_4, 'numeric');
            app.Phaseangledeg2EditFieldT2.Limits = [0 Inf];
            app.Phaseangledeg2EditFieldT2.Enable = 'off';
            app.TstartsLabel_4 = uilabel(app.VoltagesourceTab_4);
            app.TstartsLabel_4.Text = 'Tstart [s]:';
            app.Tstarts2EditFieldT2 = uieditfield(app.VoltagesourceTab_4, 'numeric');
            app.Tstarts2EditFieldT2.Limits = [-1 100];
            app.Tstarts2EditFieldT2.Enable = 'off';
            app.Tstarts2EditFieldT2.Value = -1;
            app.TstopsLabel_4 = uilabel(app.VoltagesourceTab_4);
            app.TstopsLabel_4.Text = 'Tstop [s]:';
            app.Tstops2EditFieldT2 = uieditfield(app.VoltagesourceTab_4, 'numeric');
            app.Tstops2EditFieldT2.Limits = [-1 100];
            app.Tstops2EditFieldT2.Enable = 'off';
            app.Tstops2EditFieldT2.Value = 100;
            app.ImpedancesTab_4 = uitab(app.TabGroup2_4);
            app.ImpedancesTab_4.Title = 'Impedances';
            app.RLabel_7 = uilabel(app.ImpedancesTab_4);
            app.RLabel_7.Text = 'R [Ω]';
            app.LmHLabel_7 = uilabel(app.ImpedancesTab_4);
            app.LmHLabel_7.Text = 'L [mH]';
            app.CFLabel_4 = uilabel(app.ImpedancesTab_4);
            app.CFLabel_4.Text = 'C [µF]';
            app.GroundinggridLabel_4 = uilabel(app.ImpedancesTab_4);
            app.GroundinggridLabel_4.Text = 'Grounding grid:';
            app.RGG2EditFieldT2 = uieditfield(app.ImpedancesTab_4, 'numeric');
            app.RGG2EditFieldT2.Limits = [1e-05 Inf];
            app.RGG2EditFieldT2.Enable = 'off';
            app.RGG2EditFieldT2.Value = 1e-05;
            app.LGG2EditFieldT2 = uieditfield(app.ImpedancesTab_4, 'numeric');
            app.LGG2EditFieldT2.Limits = [0 Inf];
            app.LGG2EditFieldT2.Enable = 'off';
            app.CGG2EditFieldT2 = uieditfield(app.ImpedancesTab_4, 'numeric');
            app.CGG2EditFieldT2.Limits = [0 Inf];
            app.CGG2EditFieldT2.Enable = 'off';
            app.NeutralconnectionLabel_4 = uilabel(app.ImpedancesTab_4);
            app.NeutralconnectionLabel_4.Text = 'Neutral connection:';
            app.RNC2EditFieldT2 = uieditfield(app.ImpedancesTab_4, 'numeric');
            app.RNC2EditFieldT2.Limits = [1e-05 Inf];
            app.RNC2EditFieldT2.Enable = 'off';
            app.RNC2EditFieldT2.Value = 1e-05;
            app.LNC2EditFieldT2 = uieditfield(app.ImpedancesTab_4, 'numeric');
            app.LNC2EditFieldT2.Limits = [0 Inf];
            app.LNC2EditFieldT2.Enable = 'off';
            app.CNC2EditFieldT2 = uieditfield(app.ImpedancesTab_4, 'numeric');
            app.CNC2EditFieldT2.Limits = [0 Inf];
            app.CNC2EditFieldT2.Enable = 'off';
            app.LmHLabel_8 = uilabel(app.ImpedancesTab_4);
            app.LmHLabel_8.Text = 'L [mH]';
            app.RLabel_8 = uilabel(app.ImpedancesTab_4);
            app.RLabel_8.Text = 'R [Ω]';
            app.Zerosequence0Label_4 = uilabel(app.ImpedancesTab_4);
            app.Zerosequence0Label_4.Text = 'Zero sequence (0):';
            app.R0S2EditFieldT2 = uieditfield(app.ImpedancesTab_4, 'numeric');
            app.R0S2EditFieldT2.Limits = [1e-05 Inf];
            app.R0S2EditFieldT2.Enable = 'off';
            app.R0S2EditFieldT2.Value = 1e-05;
            app.L0S2EditFieldT2 = uieditfield(app.ImpedancesTab_4, 'numeric');
            app.L0S2EditFieldT2.Limits = [0 Inf];
            app.L0S2EditFieldT2.Enable = 'off';
            app.PositivesequenceLabel_4 = uilabel(app.ImpedancesTab_4);
            app.PositivesequenceLabel_4.Text = 'Positive sequence (+):';
            app.R1S2EditFieldT2 = uieditfield(app.ImpedancesTab_4, 'numeric');
            app.R1S2EditFieldT2.Limits = [1e-05 Inf];
            app.R1S2EditFieldT2.Enable = 'off';
            app.R1S2EditFieldT2.Value = 1e-05;
            app.L1S2EditFieldT2 = uieditfield(app.ImpedancesTab_4, 'numeric');
            app.L1S2EditFieldT2.Limits = [0 Inf];
            app.L1S2EditFieldT2.Enable = 'off';
            app.ThveninEqLabel_4 = uilabel(app.ImpedancesTab_4);
            app.ThveninEqLabel_4.Text = 'Thévenin Eq.';
            app.SubstationLabel_4 = uilabel(app.ImpedancesTab_4);
            app.SubstationLabel_4.Text = 'Substation';
            app.SoilresistivityTab = uitab(app.TabGroup);
            app.SoilresistivityTab.Title = 'Soil resistivity';
            app.UITableSoilRes = uitable(app.SoilresistivityTab);
            app.UITableSoilRes.ColumnName = {'Soil resistivity [Ω.m]:'};
            app.UITableSoilRes.RowName = {};
            app.UITableSoilRes.ColumnEditable = true;
            app.DefaultsoilresistivitymLabel = uilabel(app.SoilresistivityTab);
            app.DefaultsoilresistivitymLabel.Text = 'Default soil resistivity [Ω.m]:';
            app.DefaultSoilResEditField = uieditfield(app.SoilresistivityTab, 'numeric');
            app.DefaultSoilResEditField.Limits = [0 Inf];
            app.DefaultSoilResEditField.Value = 100;
            app.SpecifyindividualsectionsLabel_2 = uilabel(app.SoilresistivityTab);
            app.SpecifyindividualsectionsLabel_2.Text = 'Specify individual sections:';
            app.btnLoadSoilRes = uibutton(app.SoilresistivityTab, 'push');
            app.btnLoadSoilRes.ButtonPushedFcn = createCallbackFcn(app, @btnLoadSoilResButtonPushed, true);
            app.btnLoadSoilRes.Text = '';
            app.PlotSoilResButton = uibutton(app.SoilresistivityTab, 'push');
            app.PlotSoilResButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSoilResButtonPushed, true);
            app.PlotSoilResButton.Text = 'Plot';
            app.UIAxesSoilRes = uiaxes(app.SoilresistivityTab);
            title(app.UIAxesSoilRes, 'Variations along line')
            xlabel(app.UIAxesSoilRes, 'Section')
            ylabel(app.UIAxesSoilRes, 'Resistivity [Ω.m]')
            app.GroundimpTab = uitab(app.TabGroup);
            app.GroundimpTab.Title = 'Ground imp.';
            app.UITableGroundImp = uitable(app.GroundimpTab);
            app.UITableGroundImp.ColumnName = {'R [Ω]'; 'L [mH]'; 'C [µF]'};
            app.UITableGroundImp.RowName = {};
            app.UITableGroundImp.ColumnEditable = true;
            app.SpecifyindividualsectionsLabel_3 = uilabel(app.GroundimpTab);
            app.SpecifyindividualsectionsLabel_3.Text = 'Specify individual sections:';
            app.btnLoadGroundImp = uibutton(app.GroundimpTab, 'push');
            app.btnLoadGroundImp.ButtonPushedFcn = createCallbackFcn(app, @btnLoadGroundImpButtonPushed, true);
            app.btnLoadGroundImp.Text = '';
            app.PlotGroundImpButton = uibutton(app.GroundimpTab, 'push');
            app.PlotGroundImpButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGroundImpButtonPushed, true);
            app.PlotGroundImpButton.Text = 'Plot';
            app.RLabel_9 = uilabel(app.GroundimpTab);
            app.RLabel_9.Text = 'R [Ω]';
            app.LmHLabel_9 = uilabel(app.GroundimpTab);
            app.LmHLabel_9.Text = 'L [mH]';
            app.CFLabel_5 = uilabel(app.GroundimpTab);
            app.CFLabel_5.Text = 'C [µF]';
            app.DefaultgroundimpedanceLabel = uilabel(app.GroundimpTab);
            app.DefaultgroundimpedanceLabel.Text = {'Default ground '; 'impedance:'};
            app.GroundImpREditField = uieditfield(app.GroundimpTab, 'numeric');
            app.GroundImpREditField.Limits = [1e-08 Inf];
            app.GroundImpREditField.Value = 15;
            app.GroundImpLEditField = uieditfield(app.GroundimpTab, 'numeric');
            app.GroundImpLEditField.Limits = [0 Inf];
            app.GroundImpCEditField = uieditfield(app.GroundimpTab, 'numeric');
            app.GroundImpCEditField.Limits = [0 Inf];
            app.TowersLabel = uilabel(app.GroundimpTab);
            app.TowersLabel.Text = 'Towers';
            app.UIAxesGroundImp = uiaxes(app.GroundimpTab);
            title(app.UIAxesGroundImp, 'Variations along line')
            xlabel(app.UIAxesGroundImp, 'Section')
            ylabel(app.UIAxesGroundImp, 'Ground resistance [Ω]')
            app.FaultmodelTab = uitab(app.TabGroup);
            app.FaultmodelTab.Title = 'Fault model';
            app.FaultedcircuitButtonGroup = uibuttongroup(app.FaultmodelTab);
            app.FaultedcircuitButtonGroup.Title = 'Faulted circuit';
            app.Circuit1Button = uiradiobutton(app.FaultedcircuitButtonGroup);
            app.Circuit1Button.Text = 'Circuit 1';
            app.Circuit1Button.Value = true;
            app.Circuit2Button = uiradiobutton(app.FaultedcircuitButtonGroup);
            app.Circuit2Button.Enable = 'off';
            app.Circuit2Button.Text = 'Circuit 2';
            app.PhasetophaseresistanceEditFieldLabel = uilabel(app.FaultmodelTab);
            app.PhasetophaseresistanceEditFieldLabel.Text = {'Phase-to-phase '; 'resistance [Ω]:'};
            app.PhasetophaseresistanceEditField = uieditfield(app.FaultmodelTab, 'numeric');
            app.PhasetophaseresistanceEditField.Limits = [1e-05 Inf];
            app.PhasetophaseresistanceEditField.Value = 1000000000000;
            app.PhasetogroundresistanceEditFieldLabel = uilabel(app.FaultmodelTab);
            app.PhasetogroundresistanceEditFieldLabel.Text = {'Phase-to-ground'; 'resistance [Ω]:'};
            app.PhasetogroundresistanceEditField = uieditfield(app.FaultmodelTab, 'numeric');
            app.PhasetogroundresistanceEditField.Limits = [1e-05 Inf];
            app.PhasetogroundresistanceEditField.Value = 1e-05;
            app.FaultincidenceangleLabel = uilabel(app.FaultmodelTab);
            app.FaultincidenceangleLabel.Text = {'Fault incidence '; 'angle:'};
            app.FaultincidenceangleSpinner = uispinner(app.FaultmodelTab);
            app.FaultincidenceangleSpinner.Limits = [1e-05 360];
            app.FaultincidenceangleSpinner.Value = 90;
            app.FaulttypeFTYPEButtonGroup = uibuttongroup(app.FaultmodelTab);
            app.FaulttypeFTYPEButtonGroup.Title = 'Fault type (FTYPE)';
            app.AGButton = uiradiobutton(app.FaulttypeFTYPEButtonGroup);
            app.AGButton.Text = '1 - AG';
            app.AGButton.Value = true;
            app.BGButton = uiradiobutton(app.FaulttypeFTYPEButtonGroup);
            app.BGButton.Text = '2 - BG';
            app.CGButton = uiradiobutton(app.FaulttypeFTYPEButtonGroup);
            app.CGButton.Text = '3 - CG';
            app.ABButton = uiradiobutton(app.FaulttypeFTYPEButtonGroup);
            app.ABButton.Text = '4 - AB';
            app.BCButton = uiradiobutton(app.FaulttypeFTYPEButtonGroup);
            app.BCButton.Text = '5 - BC';
            app.CAButton = uiradiobutton(app.FaulttypeFTYPEButtonGroup);
            app.CAButton.Text = '6 - CA';
            app.ABGButton = uiradiobutton(app.FaulttypeFTYPEButtonGroup);
            app.ABGButton.Text = '7 - ABG ';
            app.BCGButton = uiradiobutton(app.FaulttypeFTYPEButtonGroup);
            app.BCGButton.Text = '8 - BCG';
            app.CAGButton = uiradiobutton(app.FaulttypeFTYPEButtonGroup);
            app.CAGButton.Text = '9 - CAG';
            app.ABCButton = uiradiobutton(app.FaulttypeFTYPEButtonGroup);
            app.ABCButton.Text = '10 - ABC';
            app.ABCGButton = uiradiobutton(app.FaulttypeFTYPEButtonGroup);
            app.ABCGButton.Text = '11 - ABCG';
            app.UITableFaultSec = uitable(app.FaultmodelTab);
            app.UITableFaultSec.ColumnName = {'From'; 'To'};
            app.UITableFaultSec.RowName = {};
            app.UITableFaultSec.ColumnEditable = true;
            app.SpecifyfaultedsectionsLabel = uilabel(app.FaultmodelTab);
            app.SpecifyfaultedsectionsLabel.Text = 'Specify faulted sections:';
            app.CyclesbeforefaultSpinnerLabel = uilabel(app.FaultmodelTab);
            app.CyclesbeforefaultSpinnerLabel.Text = 'Cycles before fault:';
            app.CyclesbeforefaultSpinner = uispinner(app.FaultmodelTab);
            app.CyclesbeforefaultSpinner.Limits = [0 Inf];
            app.CyclesbeforefaultSpinner.RoundFractionalValues = 'on';
            app.CyclesbeforefaultSpinner.ValueDisplayFormat = '%.0f';
            app.CyclesbeforefaultSpinner.Value = 2;
            app.OutputsTab = uitab(app.TabGroup);
            app.OutputsTab.Title = 'Outputs';
            app.Terminal1phasecurrentsCheckBox = uicheckbox(app.OutputsTab);
            app.Terminal1phasecurrentsCheckBox.Enable = 'off';
            app.Terminal1phasecurrentsCheckBox.Text = 'Terminal 1 phase currents';
            app.Terminal1phasecurrentsCheckBox.Value = true;
            app.RequestoutputsLabel = uilabel(app.OutputsTab);
            app.RequestoutputsLabel.Text = 'Request outputs:';
            app.Terminal1phasevoltagesCheckBox = uicheckbox(app.OutputsTab);
            app.Terminal1phasevoltagesCheckBox.Text = 'Terminal 1 phase voltages';
            app.Terminal1phasevoltagesCheckBox.Value = true;
            app.Terminal2phasevoltagesCheckBox = uicheckbox(app.OutputsTab);
            app.Terminal2phasevoltagesCheckBox.Text = 'Terminal 2 phase voltages';
            app.Terminal2phasevoltagesCheckBox.Value = true;
            app.Terminal2phasecurrentsCheckBox = uicheckbox(app.OutputsTab);
            app.Terminal2phasecurrentsCheckBox.Enable = 'off';
            app.Terminal2phasecurrentsCheckBox.Text = 'Terminal 2 phase currents';
            app.Terminal2phasecurrentsCheckBox.Value = true;
            app.FaultbranchphasecurrentsCheckBox = uicheckbox(app.OutputsTab);
            app.FaultbranchphasecurrentsCheckBox.Text = 'Fault branch phase currents';
            app.FaultbranchphasecurrentsCheckBox.Value = true;
            app.FaultbranchphasevoltagesCheckBox = uicheckbox(app.OutputsTab);
            app.FaultbranchphasevoltagesCheckBox.Text = 'Fault branch phase voltages';
            app.FaultbranchphasevoltagesCheckBox.Value = true;
            app.ShieldwirereturncurrentsCheckBox = uicheckbox(app.OutputsTab);
            app.ShieldwirereturncurrentsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShieldwirereturncurrentsCheckBoxValueChanged, true);
            app.ShieldwirereturncurrentsCheckBox.Text = 'Shield wire return currents';
            app.ShieldwirereturncurrentsCheckBox.Value = true;
            app.TowertogroundvoltagesCheckBox = uicheckbox(app.OutputsTab);
            app.TowertogroundvoltagesCheckBox.Text = 'Tower-to-ground voltages';
            app.TowertogroundvoltagesCheckBox.Value = true;
            app.TowertogroundcurrentsCheckBox = uicheckbox(app.OutputsTab);
            app.TowertogroundcurrentsCheckBox.Text = 'Tower-to-ground currents';
            app.TowertogroundcurrentsCheckBox.Value = true;
            app.UITableTow2Ground = uitable(app.OutputsTab);
            app.UITableTow2Ground.ColumnName = {'From'; 'To'};
            app.UITableTow2Ground.RowName = {};
            app.UITableTow2Ground.ColumnEditable = true;
            app.SpecifysectionsLabel = uilabel(app.OutputsTab);
            app.SpecifysectionsLabel.Text = 'Specify sections:';
            app.SpecifysectionsLabel_2 = uilabel(app.OutputsTab);
            app.SpecifysectionsLabel_2.Text = 'Specify sections:';
            app.UITableSWCurr = uitable(app.OutputsTab);
            app.UITableSWCurr.ColumnName = {'From'; 'To'};
            app.UITableSWCurr.RowName = {};
            app.UITableSWCurr.ColumnEditable = true;
            app.OrsectionsbeforeandafterfaultTowShuntParamCheckBox = uicheckbox(app.OutputsTab);
            app.OrsectionsbeforeandafterfaultTowShuntParamCheckBox.ValueChangedFcn = createCallbackFcn(app, @OrsectionsbeforeandafterfaultTowShuntParamCheckBoxValueChanged, true);
            app.OrsectionsbeforeandafterfaultTowShuntParamCheckBox.Text = 'Or sections before and after fault:';
            app.SpinnerTow2Ground = uispinner(app.OutputsTab);
            app.SpinnerTow2Ground.Limits = [0 Inf];
            app.SpinnerTow2Ground.RoundFractionalValues = 'on';
            app.SpinnerTow2Ground.ValueDisplayFormat = '%.0f';
            app.SpinnerTow2Ground.Enable = 'off';
            app.SpinnerTow2Ground.Value = 10;
            app.SpinnerSWCurr = uispinner(app.OutputsTab);
            app.SpinnerSWCurr.Limits = [0 Inf];
            app.SpinnerSWCurr.RoundFractionalValues = 'on';
            app.SpinnerSWCurr.ValueDisplayFormat = '%.0f';
            app.SpinnerSWCurr.Enable = 'off';
            app.SpinnerSWCurr.Value = 10;
            app.OrsectionsbeforeandafterfaultShWireCurrCheckBox = uicheckbox(app.OutputsTab);
            app.OrsectionsbeforeandafterfaultShWireCurrCheckBox.ValueChangedFcn = createCallbackFcn(app, @OrsectionsbeforeandafterfaultShWireCurrCheckBoxValueChanged, true);
            app.OrsectionsbeforeandafterfaultShWireCurrCheckBox.Text = 'Or sections before and after fault:';
            app.RunTab = uitab(app.TabGroup);
            app.RunTab.Title = 'Run';
            app.MakeLCCfilesButton = uibutton(app.RunTab, 'push');
            app.MakeLCCfilesButton.ButtonPushedFcn = createCallbackFcn(app, @MakeLCCfilesButtonPushed, true);
            app.MakeLCCfilesButton.Text = 'Make LCC files';
            app.ConsoleTextAreaLabel = uilabel(app.RunTab);
            app.ConsoleTextAreaLabel.Text = 'Console:';
            app.ConsoleTextArea = uitextarea(app.RunTab);
            app.ConsoleTextArea.Editable = 'off';
            app.MakemainATPfilesButton = uibutton(app.RunTab, 'push');
            app.MakemainATPfilesButton.ButtonPushedFcn = createCallbackFcn(app, @MakemainATPfilesButtonPushed, true);
            app.MakemainATPfilesButton.Text = 'Make main ATP file(s)';
            app.RunATPsolverButton = uibutton(app.RunTab, 'push');
            app.RunATPsolverButton.ButtonPushedFcn = createCallbackFcn(app, @RunATPsolverButtonPushed, true);
            app.RunATPsolverButton.Text = 'Run ATP solver';
            app.StepbystepprocessLabel = uilabel(app.RunTab);
            app.StepbystepprocessLabel.Text = 'Step-by-step process:';
            app.Label = uilabel(app.RunTab);
            app.Label.Text = '1';
            app.Label_10 = uilabel(app.RunTab);
            app.Label_10.Text = '2';
            app.Label_11 = uilabel(app.RunTab);
            app.Label_11.Text = '3';
            app.SavedataButton = uibutton(app.RTFSRealisticTransmissionLineFaultSimulatorUIFigure, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Text = 'Save data';
            app.LoadfromfileButton = uibutton(app.RTFSRealisticTransmissionLineFaultSimulatorUIFigure, 'push');
            app.LoadfromfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfromfileButtonPushed, true);
            app.LoadfromfileButton.Text = 'Load from file';
            app.ConductordatabaseButton = uibutton(app.RTFSRealisticTransmissionLineFaultSimulatorUIFigure, 'push');
            app.ConductordatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @ConductordatabaseButtonPushed, true);
            app.ConductordatabaseButton.Text = 'Conductor database';
            app.ExitButton = uibutton(app.RTFSRealisticTransmissionLineFaultSimulatorUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = rtfs_main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RTFSRealisticTransmissionLineFaultSimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RTFSRealisticTransmissionLineFaultSimulatorUIFigure)
        end
    end
end